classdef linecablelab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainform                       matlab.ui.Figure
        ExitButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        LoadinputsessionButton         matlab.ui.control.Button
        SaveinputsessionButton         matlab.ui.control.Button
        ConductordatabaseButton        matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        ExperimentsTab                 matlab.ui.container.Tab
        LoadFEMPathButton              matlab.ui.control.Button
        FEMPathEditField               matlab.ui.control.EditField
        FEMPathEditFieldLabel          matlab.ui.control.Label
        EnableFEMengineCheckBox        matlab.ui.control.CheckBox
        LoadMATButton                  matlab.ui.control.Button
        MATfileEditField               matlab.ui.control.EditField
        MATfileEditFieldLabel          matlab.ui.control.Label
        LoadpulZYfromuserdefinedMATfileCheckBox  matlab.ui.control.CheckBox
        LoadCYZButton                  matlab.ui.control.Button
        CYZfileEditField               matlab.ui.control.EditField
        CYZfiletEditFieldLabel         matlab.ui.control.Label
        LoadpulZYfromLineCable_DataCheckBox  matlab.ui.control.CheckBox
        LoadWrapperScriptButton        matlab.ui.control.Button
        WrapperScriptEditField         matlab.ui.control.EditField
        WrapperScriptEditFieldLabel    matlab.ui.control.Label
        UseexternalprocesstocomputelineparametersCheckBox  matlab.ui.control.CheckBox
        SimToolTab                     matlab.ui.container.Tab
        MatchwithcharacteristicimpedanceCheckBox  matlab.ui.control.CheckBox
        UITableTermImpedances          matlab.ui.control.Table
        TerminationimpedancesLabel     matlab.ui.control.Label
        RequestNLTplotsCheckBox        matlab.ui.control.CheckBox
        SourceamplitudepuEditField     matlab.ui.control.NumericEditField
        SourceamplitudepuEditFieldLabel  matlab.ui.control.Label
        StarttimesEditField            matlab.ui.control.NumericEditField
        StarttimesEditFieldLabel       matlab.ui.control.Label
        SimulationtimesEditField       matlab.ui.control.NumericEditField
        SimulationtimesLabel           matlab.ui.control.Label
        SamplingrateHzEditField        matlab.ui.control.NumericEditField
        SamplingrateHzEditFieldLabel   matlab.ui.control.Label
        SimulationtypeDropDown         matlab.ui.control.DropDown
        SimulationtypeDropDownLabel    matlab.ui.control.Label
        EMIsimulationPanel             matlab.ui.container.Panel
        BuildEMImodelButton            matlab.ui.control.Button
        TowerresistanceEditField       matlab.ui.control.NumericEditField
        TowerresistanceLabel           matlab.ui.control.Label
        ShieldwiresListBox             matlab.ui.control.ListBox
        ShieldwiresListBoxLabel        matlab.ui.control.Label
        CoatingresistivitymEditField   matlab.ui.control.NumericEditField
        CoatingresistivitymEditFieldLabel  matlab.ui.control.Label
        TargetphaseDropDown            matlab.ui.control.DropDown
        TargetphaseDropDownLabel       matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        SubdivisionmEditField          matlab.ui.control.NumericEditField
        SubdivisionmEditFieldLabel     matlab.ui.control.Label
        LoadTargetCSVButton            matlab.ui.control.Button
        TargetCSVEditField             matlab.ui.control.EditField
        TargetCSVEditFieldLabel        matlab.ui.control.Label
        LoadSourceCSVButton            matlab.ui.control.Button
        SourceCSVEditField             matlab.ui.control.EditField
        SourceCSVEditFieldLabel        matlab.ui.control.Label
        EnableNLTengineCheckBox        matlab.ui.control.CheckBox
        LinecomputationsTab            matlab.ui.container.Tab
        FittingoptionsPanel            matlab.ui.container.Panel
        BFTOLdBEditField               matlab.ui.control.NumericEditField
        BFTOLdBEditFieldLabel          matlab.ui.control.Label
        VFTOLpuEditField               matlab.ui.control.NumericEditField
        VFTOLLabel                     matlab.ui.control.Label
        MaxPolesEditField              matlab.ui.control.NumericEditField
        MaxPolesEditFieldLabel         matlab.ui.control.Label
        MinPolesEditField              matlab.ui.control.NumericEditField
        MinPolesEditFieldLabel         matlab.ui.control.Label
        ExportlinemodelPanel           matlab.ui.container.Panel
        ATPcompliantPCHJMartimodelCheckBox  matlab.ui.control.CheckBox
        ATPTYPE94fitULMmodelDeContietalCheckBox  matlab.ui.control.CheckBox
        ATPDrawcompliantXMLfileCheckBox  matlab.ui.control.CheckBox
        EMTPcompliantLineCable_DataMATfileCheckBox  matlab.ui.control.CheckBox
        PlotsavePanel                  matlab.ui.container.Panel
        SaveallplotsCheckBox           matlab.ui.control.CheckBox
        SavedatatoMATfilesCheckBox     matlab.ui.control.CheckBox
        RequestmodalparametersplotsCheckBox  matlab.ui.control.CheckBox
        RequestpulparametersplotsCheckBox  matlab.ui.control.CheckBox
        FrequencysweeplogspacedpointsPanel  matlab.ui.container.Panel
        PointsperdecadeSpinner         matlab.ui.control.Spinner
        PointsperdecadeSpinnerLabel    matlab.ui.control.Label
        Upperdecade10UHzSpinner        matlab.ui.control.Spinner
        Upperdecade10UHzSpinnerLabel   matlab.ui.control.Label
        Lowerdecade10LHzSpinner        matlab.ui.control.Spinner
        Lowerdecade10LHzSpinnerLabel   matlab.ui.control.Label
        UniformbottomsoillayerPanel    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        PermittivitypuEditField        matlab.ui.control.NumericEditField
        PermeabilitypuEditField        matlab.ui.control.NumericEditField
        ResistivitymEditField          matlab.ui.control.NumericEditField
        PermittivitypuEditFieldLabel   matlab.ui.control.Label
        PermeabilitypuEditFieldLabel   matlab.ui.control.Label
        ResistivitymEditFieldLabel     matlab.ui.control.Label
        UITableLayers                  matlab.ui.control.Table
        Layer2Delete                   matlab.ui.control.NumericEditField
        CurrentrowLabel_2              matlab.ui.control.Label
        DeleteButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        UppersoillayersLabel           matlab.ui.control.Label
        GroundreturnmodelPanel         matlab.ui.container.Panel
        ModaldecompositionDropDown     matlab.ui.control.DropDown
        ModaldecompositionDropDownLabel  matlab.ui.control.Label
        OtherYListBox                  matlab.ui.control.ListBox
        OtherYListBoxLabel             matlab.ui.control.Label
        AdmittanceDropDown             matlab.ui.control.DropDown
        AdmittanceDropDownLabel        matlab.ui.control.Label
        OtherZListBox                  matlab.ui.control.ListBox
        OtherZListBoxLabel             matlab.ui.control.Label
        ImpedanceDropDown              matlab.ui.control.DropDown
        ImpedanceDropDownLabel         matlab.ui.control.Label
        FDsoilDropDown                 matlab.ui.control.DropDown
        FDsoilDropDownLabel            matlab.ui.control.Label
        ComputelineparametersCheckBox  matlab.ui.control.CheckBox
        CasedataTab                    matlab.ui.container.Tab
        LinecrosssectionconductordataPanel  matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        ExportcrosssectiontoATPDrawButton  matlab.ui.control.Button
        LinelengthmEditField           matlab.ui.control.NumericEditField
        LinelengthmEditFieldLabel      matlab.ui.control.Label
        row2Delete                     matlab.ui.control.NumericEditField
        CurrentrowLabel                matlab.ui.control.Label
        DeleterowButton                matlab.ui.control.Button
        AddrowButton                   matlab.ui.control.Button
        RefreshPreview                 matlab.ui.control.Button
        GeneralPanel                   matlab.ui.container.Panel
        ProjectdescriptionTextArea     matlab.ui.control.TextArea
        ProjectdescriptionLabel        matlab.ui.control.Label
        JobIDEditField                 matlab.ui.control.EditField
        JobIDLabel                     matlab.ui.control.Label
        LoadWorkdirButton              matlab.ui.control.Button
        WorkdirectoryEditField         matlab.ui.control.TextArea
        WorkdirectoryEditFieldLabel    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        formulasZ struct
        formulasY struct
        simuTypes struct
    end

    methods (Access = private)

        function [out] = uiquestdlg(app,msg,title,opts,defaultopt,cancelopt)
            h_fig = findall(0,'Name','LineCableLab');
            out=uiconfirm(h_fig,msg,title,'Options',opts,'DefaultOption',defaultopt,'CancelOption',cancelopt);
        end

        function [out] = uimsgbox(app,msg,title,icon)
            h_fig = findall(0,'Name','LineCableLab');
            out = h_fig;
            uialert(h_fig,msg,title,'Icon',icon);
        end

        function [isgood, msg] = validateWorkdir(app)
            fname=string(app.WorkdirectoryEditField.Value);
            isgood=true;
            msg='';
            if  ~exist(fname, 'dir')
                msg='Invalid work directory!';
                isgood=false;
                return;
            end
        end


        function out = redefRowLabels(app)
            nrows = size(app.UITable.Data,1);
            app.UITable.RowName=string(1:nrows);

            % ph_order = str2double(app.UITable.Data.PhaseNum);
            % Nph = unique(ph_order);
            % Nph = size(Nph(Nph~=0),1);
            % app.UITableTermImpedances.RowName=string(1:Nph);

        end

        function out = redefLayerLabels(app)
            nrows = size(app.UITableLayers.Data,1);
            app.UITableLayers.RowName=string(1:nrows);
            app.UITableLayers.ColumnFormat = {'numeric'};
            app.UITableLayers.ColumnFormat = repmat( {'numeric'}, 1, 4 );

%             % Total table width (excluding vertical scrollbar)
%             totalWidth = 503 - 50;  
% 
%             % Number of columns (excluding the row names)
%             numColumns = 4;
% 
%             % Calculate the width for each column
%             equalColumnWidth = floor(totalWidth / numColumns);  % Using floor to avoid decimal pixel values
% 
%             % Create a cell array to set the 'ColumnWidth' property
%             columnWidths = repmat({equalColumnWidth}, 1, numColumns);  % A cell array with equal widths
% 
%             % Apply the column widths
%             app.UITableLayers.ColumnWidth = columnWidths;

        end

        function out = addNewLayer(app)
            newrow={100, 1, 1, 1};
            app.UITableLayers.Data=[app.UITableLayers.Data; newrow];
            redefLayerLabels(app);
        end
        
        function results = saveAllData(app)

            results=false;
            %[testdir,msg]=validateWorkDir(app);
            %if ~testdir
            %    f = msgbox(msg, 'You lose, fella.','error');
            %    return;
            %end

            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                % f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            %ok now do stuff
            projID=app.JobIDEditField.Value;
            [~,wdir] = uiputfile('*.mat','Save input',[projID '.mat']);

            %             wdir=app.WorkingdirectoryEditField.Value;
            if wdir ~= 0
                projdir=fullfile(wdir);
                %             orig_state = warning;warning('off','all');
                %             mkdir(projdir); warning(orig_state);
                %             mkdir(projdir);
                fname=['InputSession_' projID '.mat'];

                if isfile(fullfile(projdir,fname))
                    answer = uiquestdlg(app,sprintf('A previous input session file was found. Do you want to replace it?'), ...
                        'Warning', ...
                        ["Yes","No"],1,2);

                    if strcmp(answer,'No')
                        return;
                    end
                else
                    ohlt_data = readAllAppValues(app);
                    save(fullfile(projdir,fname),'ohlt_data');
                    results=true;
                end
            end
        end

        function results = writeAllAppValues(app,ohlt_data)

            props = properties(app);
            
            try
                for iprop = 1:length(props)
                    thisprop = props{iprop};

                    if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                            strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                            strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                            strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable') || ...
                            strcmp(app.(thisprop).Type,'uilistbox')
                        
                        if isprop(app.(thisprop),'Items')
                            app.(thisprop).Items=ohlt_data.(thisprop).Items;
                        end

                        if isprop(app.(thisprop),'Value')
                            app.(thisprop).Value=ohlt_data.(thisprop).Value;
                        end

                        app.(thisprop).Enable=ohlt_data.(thisprop).Enable;
                        app.(thisprop).Visible=ohlt_data.(thisprop).Visible;

                        if isprop(app.(thisprop),'Editable')
                            app.(thisprop).Editable=ohlt_data.(thisprop).Editable;
                        end

                        if isprop(app.(thisprop),'List')
                            app.(thisprop).List=ohlt_data.(thisprop).List;
                        end

                        if isprop(app.(thisprop),'Data')
                            app.(thisprop).Data=ohlt_data.(thisprop).Data;
                        end

                    end

                end
            catch
                % Input session loaded poorly, probably due to
                % incompatibility with the current GUI data struct. Since
                % I ain't gonna actually fix this now, let us try at least to
                % refresh the form state to something decent
                redefRowLabels(app);
                redefFormulas(app);
                redefAdditionalFormulas(app);
                redefImpedancesTable(app);
                msg = sprintf('Unable to load all data from the supplied file, probably due to incompatibilities with the current LineCableLab version. Double check your inputs before lauching this run.');
                f = uimsgbox(app, msg, 'Warning!','warning');
            end
        end

        function appdata = readAllAppValues(app)
            appdata=struct;

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable') || ...
                        strcmp(app.(thisprop).Type,'uilistbox')

                    appdata.(thisprop)=struct;

                    if isprop(app.(thisprop),'Value')
                        appdata.(thisprop).Value=app.(thisprop).Value;
                    end

                    appdata.(thisprop).Enable=app.(thisprop).Enable;
                    appdata.(thisprop).Visible=app.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        appdata.(thisprop).Editable=app.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        appdata.(thisprop).List=app.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Items')
                        appdata.(thisprop).Items=app.(thisprop).Items;
                    end


                    if isprop(app.(thisprop),'Data')
                        appdata.(thisprop).Data=app.(thisprop).Data;
                    end

                end

            end
        end

        function [isgood, msg] = validateJobID(app)
            jobID=app.JobIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(jobID)
                isgood=false;
                msg='You must specify a JobID!';
                return;
            else
                bad = ismember(BadChar, jobID);
                if any(bad)
                    msg = ['JobID name contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(jobID < 32)
                    msg = ['JobID name contains non printable characters, ASCII:', sprintf(' %d', jobID(jobID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(jobID) && (jobID(end) == ' ' || jobID(end) == '.')
                    msg = 'JobID name must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(jobID);
                    if any(strcmpi(name, BadName))
                        msg = ['JobID name not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end

        
        function results = redefFormulas(app)

            results=[];

            allheights = str2double((app.UITable.Data{:,3}));
            nrows = size(app.UITableLayers.Data,1);
            allTypesZ = [app.formulasZ.Type];
            allNamesZ = {app.formulasZ.DisplayName};
            allTypesY = [app.formulasY.Type];
            allNamesY = {app.formulasY.DisplayName};

            if all(allheights>0)
                idxZ = find(allTypesZ == 1);
                idxY = find(allTypesY == 1);
            elseif all(allheights<0)
                idxZ = find(allTypesZ == 2);
                idxY = find(allTypesY == 2);
            else
                idxZ = find(allTypesZ == 3);
                idxY = find(allTypesY == 3);
            end
            app.ImpedanceDropDown.Items=allNamesZ(idxZ);
            app.AdmittanceDropDown.Items =allNamesY(idxY);
            
        end
        
        function results = redefAdditionalFormulas(app)

            results=[];

            allheights = str2double((app.UITable.Data{:,3}));
            nrows = size(app.UITableLayers.Data,1);
            allTypesZ = [app.formulasZ.Type];
            allNamesZ = {app.formulasZ.DisplayName};
            allTypesY = [app.formulasY.Type];
            allNamesY = {app.formulasY.DisplayName};

            chosenZ = app.ImpedanceDropDown.Value;
            chosenY = app.AdmittanceDropDown.Value;

            if all(allheights>0)
                idxZ = find(allTypesZ == 1 & ~strcmp(allNamesZ,chosenZ));
                idxY = find(allTypesY == 1 & ~strcmp(allNamesY,chosenY));
            elseif all(allheights<0)
                idxZ = find(allTypesZ == 2 & ~strcmp(allNamesZ,chosenZ));
                idxY = find(allTypesY == 2 & ~strcmp(allNamesY,chosenY));
            else
                idxZ = find(allTypesZ == 3 & ~strcmp(allNamesZ,chosenZ));
                idxY = find(allTypesY == 3 & ~strcmp(allNamesY,chosenY));
            end


            app.OtherZListBox.Items = allNamesZ(idxZ);
            app.OtherYListBox.Items = allNamesY(idxY);
            
        end
        
        function results = writeLineFun(app, fname)
            results=false;
            
            nrows = size(app.UITableLayers.Data,1);
            if nrows > 0
                LayeredData=cell2matButNotStupid(app,app.UITableLayers.Data);
            end


            try

                out{1,1}=    'function [length,Ncon,soil,h,d,Geom,Nph]=LineData_fun()';
                out{end+1,1}='%% Line geometry';
                out{end+1,1}='%% 1 column -- number of phase (set to 0 for Kron reduction)';
                out{end+1,1}='%% 2 column -- x position of each conduntor in meters';
                out{end+1,1}='%% 3 column -- y position of each coductor in meters';
                out{end+1,1}='%% 4 column -- internal radii of each conductor';
                out{end+1,1}='%% 5 column -- external radii of each conductor';
                out{end+1,1}='%% 6 column -- resistivity of the conductor';
                out{end+1,1}='%% 7 column -- permeability of the conductor';
                out{end+1,1}='%% 8 column -- external radii of insulation';
                out{end+1,1}='%% 9 column -- relative permeability of insulation';
                out{end+1,1}='%% 10 column -- relative permittivity of insulation';
                out{end+1,1}='%% 11 column -- line length in m';
                out{end+1,1}=sprintf('length = %6.6f;',app.LinelengthmEditField.Value);
                out{end+1,1}='Geom=[ ...';
                for k=1:size(app.UITable.Data,1)
                    out{end+1,1}=sprintf('%6.0f  %6.6f  %6.6f  %6.6f  %6.6f  %6.6g  %6.6f  %6.6f  %6.6f  %6.6f  length',app.UITable.Data{k,:});
                end
                out{end+1,1}='];';
                out{end+1,1}='Ncon = size(Geom,1);';
                out{end+1,1}='%% Variables';
                out{end+1,1}='e0=8.854187817e-12;';
                out{end+1,1}='m0=4*pi*1e-7;';
                out{end+1,1}='%% Rearrange data matrix to perform bundle reduction';
                out{end+1,1}='Geom = reorderGeoMatrix(Geom);';
                out{end+1,1}='ph_order = Geom(:,1);';
                out{end+1,1}='Nph = unique(ph_order);';
                out{end+1,1}='Nph = size(Nph(Nph~=0),1);';
                out{end+1,1}='%% Height of line calculation';
                out{end+1,1}='[h]=height_fun(Ncon,Geom);';
                out{end+1,1}='%% Distances calculations';
                out{end+1,1}='[d]=distance_fun(Ncon,Geom);';
                out{end+1,1}='%% Earth electric parameters';
                out{end+1,1}=sprintf('soil.erg = %6.6f;',app.PermittivitypuEditField.Value);
                out{end+1,1}=sprintf('soil.m_g = m0*%6.6f;',app.PermeabilitypuEditField.Value);
                out{end+1,1}=sprintf('soil.sigma_g = 1/%6.6f;',app.ResistivitymEditField.Value);
                if nrows > 0
                    for i=1:nrows
                        out{end+1,1}=sprintf('soil.layer(%d).erg = %6.6f;',i,LayeredData(i,3));
                        out{end+1,1}=sprintf('soil.layer(%d).m_g = m0*%6.6f;',i,LayeredData(i,2));
                        out{end+1,1}=sprintf('soil.layer(%d).sigma_g = 1/%6.6f;',i,LayeredData(i,1));
                        out{end+1,1}=sprintf('soil.layer(%d).t = %6.6f;',i,LayeredData(i,4));
                    end
                end
                out{end+1,1}=sprintf('end');


                outstr=strjoin(out, '\n') ;
                fid = fopen(fname,'wt');
                fprintf(fid, outstr);
                fclose(fid);
                results=true;

            end
          
        end


        function results = writeMainRunFun(app, fname)
            results=false;

            try
                [Zsrc, Zflag Ysrc, Yflag] = parseZYformula(app);
                [Zsrc2, Zflag2 Ysrc2, Yflag2] = parseOtherZY(app);
                simuflag = parseSimuTypeFlag(app);
                currmfile = mfilename('fullpath');
                currPath = replace(currmfile(1:end-length(mfilename())),'\','\\');
                % if isempty(app.PythonPathEditField.Value)
                %     pycall='python3';
                % else
                %     pycall=replace(app.PythonPathEditField.Value,'\','\\');
                % end
                jobid=app.JobIDEditField.Value;
                runexternal = app.UseexternalprocesstocomputelineparametersCheckBox.Value;
                if runexternal
                    [ext_path,ext_fname,~]= fileparts(app.WrapperScriptEditField.Value);
                    ext_path=strrep(ext_path,'\','\\');
                end
                runfem = app.EnableFEMengineCheckBox.Value;
                if runfem
                    fem_path=app.FEMPathEditField.Value;
                    fem_path=strrep(fem_path,'\','\\');
                end
                out{1,1}=    '%%%% LineCableLab run';
                out{end+1,1}='%% Project description:';
                out{end+1,1}='%% -------------------';
                out{end+1,1}=sprintf('%%%% %s',strjoin(app.ProjectdescriptionTextArea.Value,'\\n%% '));
                out{end+1,1}='';
                out{end+1,1}='%%%% Startup';
                out{end+1,1}='close all;';
                out{end+1,1}='delete(allchild(0));';
                out{end+1,1}='clear all;';
                out{end+1,1}='clc;';
                out{end+1,1}='format longEng;';
                out{end+1,1}='';
                out{end+1,1}='%%%% Flags and options';
                out{end+1,1}=sprintf('runLineParametersCalc = logical(%d); %%%% Flag to compute line parameters',app.ComputelineparametersCheckBox.Value);
                out{end+1,1}=sprintf('runSimuTool = logical(%d); %%%% Flag to run NLT simulations',app.EnableNLTengineCheckBox.Value);
                out{end+1,1}=sprintf('ZYprnt = logical(%d); %%%% Flag to plot parameters',app.RequestpulparametersplotsCheckBox.Value);
                out{end+1,1}=sprintf('Modprnt = logical(%d); %%%% Flag to plot propagation characteristics',app.RequestmodalparametersplotsCheckBox.Value);
                out{end+1,1}=sprintf('NLTprnt = logical(%d); %%%% Flag to plot NLT simulations',app.RequestNLTplotsCheckBox.Value);
                out{end+1,1}=sprintf('ZYsave = logical(%d); %%%% Flag to save parameters to matfiles',app.SavedatatoMATfilesCheckBox.Value);
                out{end+1,1}=sprintf('Figsave = logical(%d); %%%% Flag to save plots to figfiles',app.SaveallplotsCheckBox.Value);
                out{end+1,1}=sprintf('export2EMTP = logical(%d); %%%% Flag to export data to a EMTP-compliant matfile',app.EMTPcompliantLineCable_DataMATfileCheckBox.Value);
                % out{end+1,1}=sprintf('export2PCH = logical(%d); % Flag to write data to a PCH JMarti model file',app.ATPcompliantPCHJMartimodelCheckBox.Value);
                out{end+1,1}=sprintf('export2XML = logical(%d); %%%% Flag to write data to a XML ATPDraw file',app.ATPDrawcompliantXMLfileCheckBox.Value);
                out{end+1,1}=sprintf('export2ULM = logical(%d); %%%% Flag to write data to fitULM file (De Conti et al. ATP implementation)',app.ATPTYPE94fitULMmodelDeContietalCheckBox.Value);
                out{end+1,1}=sprintf('FD_flag = %d; %%%% Flag for FD soil models: (0) Constant, (1) Longmire & Smith, (2) Portela, (3) Alipio & Visacro, (4) Datsios & Mikropoulos, (5) Scott, (6) Messier, (7) Visacro & Portela, (8) Visacro & Alipio, (9) Cigre',getFDmodel(app));
                out{end+1,1}=sprintf('decmp_flag = %d; %%%% Modal decomposition flag: (1) QR,(2)QR ATP,(3)simple_QR,(4)simple_QR,(5)NR,(6)NR_back,(7)SQP,(8)SQP_back,(9)LM Levenberg-Marquardt,(10)LM_back,(11)LM_fast,(12)LM_alt, (13) eigenvalue shuffle QR, (14) intercheig QR',getModalTransfmodel(app));
                % out{end+1,1}=sprintf('global pythoncall; pythoncall = ''%s'';',pycall);
                out{end+1,1}='';
                out{end+1,1}='%% Choice of main Z';
                out{end+1,1}=sprintf('Zopts.%s = logical(1);',Zflag);
                if  ~isempty(Zflag2)
                    out{end+1,1}='%% Other Z formulas for comparisons';
                    for i=1:length(Zflag2)
                        out{end+1,1}=sprintf('Zopts.%s = logical(1);',Zflag2{i});
                    end
                end
                out{end+1,1}='';
                out{end+1,1}='%% Choice of main Y';
                out{end+1,1}=sprintf('Yopts.%s=logical(1);',Yflag);
                if  ~isempty(Yflag2)
                    out{end+1,1}='%% Other Y formulas for comparisons';
                    for i=1:length(Yflag2)
                        out{end+1,1}=sprintf('Yopts.%s = logical(1);',Yflag2{i});
                    end
                end
                if app.LoadpulZYfromLineCable_DataCheckBox.Value
                    out{end+1,1}='';
                    out{end+1,1}='%% Load pul ZY from LineCable_Data file';
                    out{end+1,1}=sprintf('Zopts.%s = logical(1);','ImportCYZ');
                    out{end+1,1}=sprintf('Zopts.CYZfile = ''%s'';',strrep(app.CYZfileEditField.Value,'\','\\'));
                    out{end+1,1}=sprintf('Yopts.%s = logical(1);','ImportCYZ');
                    out{end+1,1}=sprintf('Yopts.CYZfile = ''%s'';',strrep(app.CYZfileEditField.Value,'\','\\'));
                end
                if app.LoadpulZYfromuserdefinedMATfileCheckBox.Value
                    out{end+1,1}='';
                    out{end+1,1}='%% Load pul ZY from external MAT-file';
                    out{end+1,1}=sprintf('Zopts.%s = logical(1);','ImportMAT');
                    out{end+1,1}=sprintf('Zopts.MATfile = ''%s'';',strrep(app.MATfileEditField.Value,'\','\\'));
                    out{end+1,1}=sprintf('Yopts.%s = logical(1);','ImportMAT');
                    out{end+1,1}=sprintf('Yopts.MATfile = ''%s'';',strrep(app.MATfileEditField.Value,'\','\\'));
                end
                if runfem
                    out{end+1,1}='';
                    out{end+1,1}='%% Enable FEMM solver';
                    out{end+1,1}=sprintf('Zopts.%s = logical(1);','FEMM');
                    out{end+1,1}=sprintf('Yopts.%s = logical(1);','FEMM');
                end
                out{end+1,1}='';
                out{end+1,1}='%% JobID will be appended to every file or plot generated by this run';
                out{end+1,1}=sprintf('jobid = ''%s'';',jobid);
                out{end+1,1}='';
                out{end+1,1}='%%%% Toolbox library functions';
                out{end+1,1}='currmfile = mfilename(''fullpath'');';
                out{end+1,1}='currPath = currmfile(1:end-length(mfilename()));';
                out{end+1,1}=sprintf('WORKDIR=''%s'';',currPath);
                out{end+1,1}=sprintf('addpath(%s);','WORKDIR');
                if runexternal
                    out{end+1,1}=sprintf('addpath(''%s'');',ext_path);
                end
                if runfem
                    out{end+1,1}=sprintf('addpath([%s ''fem_funs'']);','WORKDIR');
                    out{end+1,1}=sprintf('addpath(''%s''); %%%% Path to FEMM solver and wrappers',fem_path);
                end
                out{end+1,1}=sprintf('addpath([%s ''ZY_OHTL_pul_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''multilayersoil_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''mode_decomp_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''FD_soil_models_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''line_export_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''Laplace_sim_model_update'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath(fullfile(%s,''line_export_funs'',''functions''));','WORKDIR');
                out{end+1,1}=sprintf('addpath(fullfile(%s,''line_export_funs'',''vfit3''));','WORKDIR');
                out{end+1,1}='';
                out{end+1,1}='%%%% Frequency range';
                out{end+1,1}=sprintf('f = transpose(logspaceppd(%d,%d,%d));',app.Lowerdecade10LHzSpinner.Value,app.Upperdecade10UHzSpinner.Value,app.PointsperdecadeSpinner.Value);
                out{end+1,1}='freq_siz=length(f);';
                out{end+1,1}='';
                out{end+1,1}='%%%% Line characteristics';
                out{end+1,1}='[line_length,ord,soil,h,d,Geom,Nph]=LineData_fun();';
                out{end+1,1}='';
                out{end+1,1}='%%%% Soil with FD properties';
                out{end+1,1}='soilFD = soilFD_fun(soil,FD_flag,f);';
                out{end+1,1}='';
                out{end+1,1}='%%%% Calculations of line parameters';
                out{end+1,1}='if runLineParametersCalc';
                out{end+1,1}='  disp(''Computing line parameters...'');';
                out{end+1,1}='  tic';
                out{end+1,1}='  %% Calculate Z pul parameters by different earth approaches';
                out{end+1,1}='  allZ_pul = Zcalc_fun(f,ord,Nph,soilFD,h,d,Geom,currPath,jobid,Zopts);';
                % out{end+1,1}='  if ZYprnt; plotZYpul_fun(f,ord,allZ_pul,jobid); end';
                out{end+1,1}='  %% Calculate Y pul parameters by different earth approaches';
                out{end+1,1}='  allY_pul = Ycalc_fun(f,ord,Nph,soilFD,h,d,Geom,currPath,jobid,Yopts);';
                % out{end+1,1}='  if ZYprnt; plotZYpul_fun(f,ord,allY_pul,jobid); end';
                if runexternal
                    out{end+1,1}='  %% Run user-defined external process';
                    out{end+1,1}=sprintf('  %s',ext_fname);
                end
                out{end+1,1}='  %% Modal decomposition';
                out{end+1,1}='  selectZ=@(x) allZ_pul(find(strcmp({allZ_pul.VarName}, x))).Values;';
                out{end+1,1}='  selectY=@(x) allY_pul(find(strcmp({allY_pul.VarName}, x))).Values;';
                out{end+1,1}=sprintf('  Z=selectZ(''%s'');',Zsrc);
                out{end+1,1}=sprintf('  Y=selectY(''%s'');',Ysrc);
                out{end+1,1}='  [Zch_mod,Ych_mod,Zch,Ych,g_dis,a_dis,vel_dis,Ti_dis,Z_dis,Y_dis] = ...';
                out{end+1,1}=sprintf('  mode_decomp_fun(%s,%s,f,freq_siz,Nph,decmp_flag);','Z','Y');
                out{end+1,1}='  toc';
                out{end+1,1}='  %% Save files';
                out{end+1,1}='  if ZYsave';
                out{end+1,1}=sprintf('      fname = fullfile(currPath,[%s ''_LineParam.mat'']);','jobid');
                out{end+1,1}=sprintf('      save(fname,''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'');',...
                    'Z', 'Y', 'Zch_mod', 'Ych_mod', 'Zch', 'Ych', 'g_dis', 'a_dis', 'vel_dis', 'Ti_dis', 'Z_dis', 'Y_dis', ...
                    'soilFD','f','line_length', 'allZ_pul', 'allY_pul');
                out{end+1,1}='  end';               
                out{end+1,1}='else';
                out{end+1,1}=sprintf('  if isfile([%s ''_LineParam.mat'']); load([%s ''_LineParam.mat'']); end','jobid','jobid');
                out{end+1,1}='end';
                out{end+1,1}='%% Plot results';
                out{end+1,1}='if ZYprnt && exist(''allZ_pul'',''var'')==1 && exist(''allY_pul'',''var'')==1 && exist(''f'',''var'')==1 && exist(''Nph'',''var'')==1 && exist(''jobid'',''var'')==1';
                out{end+1,1}='  plotZYpul_fun(f,Nph,allZ_pul,jobid);';
                out{end+1,1}='  plotZYpul_fun(f,Nph,allY_pul,jobid);';
                out{end+1,1}='end';
                out{end+1,1}='if Modprnt && exist(''f'',''var'')==1 && exist(''Nph'',''var'')==1 && exist(''a_dis'',''var'')==1 && exist(''vel_dis'',''var'')==1 && exist(''Zch_mod'',''var'')==1 && exist(''Ti_dis'',''var'')==1 && exist(''jobid'',''var'')==1';
                out{end+1,1}='  plotwavchar_fun(f,Nph,a_dis,vel_dis,Zch_mod,Ti_dis,jobid);';
                out{end+1,1}='end';
                out{end+1,1}='';
                out{end+1,1}='%%%% NLT simulations';
                out{end+1,1}='if runSimuTool';
                out{end+1,1}='  disp(''Running NLT simulations...'');';
                out{end+1,1}='  tic';
                out{end+1,1}='  %% Set simulation parameters';
                out{end+1,1}=sprintf('  time_sim = %6.6f;',app.SimulationtimesEditField.Value);
                out{end+1,1}=sprintf('  time_cl_brkr = %6.6f;',app.StarttimesEditField.Value);
                out{end+1,1}=sprintf('  sim_flag = %d;',simuflag);
                out{end+1,1}=sprintf('  amp = %d;',app.SourceamplitudepuEditField.Value);
                out{end+1,1}=sprintf('  e = %d;',app.SamplingrateHzEditField.Value);
                out{end+1,1}='  data_t_sim=0:1/e:time_sim;';
                out{end+1,1}='  samples=max(size(data_t_sim));';
                out{end+1,1}='  f_samples=0:e/samples:(e/2);';
                out{end+1,1}='  %% Set circuit structure and terminations';
                out{end+1,1}='  Ys_dis=zeros(freq_siz,Nph);';
                out{end+1,1}='  Yr_dis=zeros(freq_siz,Nph);';
                out{end+1,1}='  phYc=extractDiagonals(list2sqmat(Ych,Nph,freq_siz,false));';
                if simuflag == 6; ph_idx=4; else; ph_idx=2; end;
                if ~app.MatchwithcharacteristicimpedanceCheckBox.Value
                    SendZVal=str2double(app.UITableTermImpedances.Data.SendZ);
                    RecZVal=str2double(app.UITableTermImpedances.Data.RecZ);
                    out{end+1,1}='  for o=1:freq_siz';
                    out{end+1,1}='    Ys_dis(o,:)=[ ...';
                    for i=1:length(SendZVal)
                        if i<ph_idx
                            out{end+1,1}='    0 ...';
                        else
                            out{end+1,1}=sprintf('    1/%6.6f ...',abs(SendZVal(i)));
                        end
                    end
                    out{end+1,1}='    ];';
                    out{end+1,1}='    Yr_dis(o,:)=[ ...';
                    for i=1:length(RecZVal)
                            out{end+1,1}=sprintf('    1/%6.6f ...',abs(RecZVal(i)));
                    end
                    out{end+1,1}='    ];';
                    out{end+1,1}='  end';
                else
                    out{end+1,1}=sprintf('  Ys_dis(:,%d:Nph)=phYc(:,%d:Nph);',ph_idx, ph_idx);
                    out{end+1,1}='  Yr_dis(:,1:Nph)=phYc(:,1:Nph);';
                end
                out{end+1,1}='  %% Main simulation loop';
                out{end+1,1}='  if sim_flag >= 1 ';
                out{end+1,1}='    [v]=td_sim(Nph,f_samples,f,g_dis,Z_dis,Y_dis,Ti_dis,Ys_dis,Yr_dis,line_length,time_cl_brkr,samples,e,data_t_sim,time_sim,amp,sim_flag);';
                out{end+1,1}='  else ';
                out{end+1,1}='    [v]=freq_scan_sim(Nph,f,g_dis,Z_dis,Y_dis,Ti_dis,Ys_dis,Yr_dis,line_length);';
                out{end+1,1}='  end ';
                out{end+1,1}='  toc';
                out{end+1,1}='  %% Save files';
                out{end+1,1}='  if ZYsave';
                out{end+1,1}=sprintf('      fname = fullfile(currPath,[%s ''_NLTSimu.mat'']);','jobid');
                out{end+1,1}=sprintf('      save(fname,''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'');',...
                    'data_t_sim', 'f', 'f_samples', 'Ys_dis', 'Yr_dis', 'phYc', 'time_sim', 'time_cl_brkr', 'sim_flag', 'amp', 'e', 'v');
                out{end+1,1}='  end';
                out{end+1,1}='else';
                out{end+1,1}=sprintf(' if isfile([%s ''_NLTSimu.mat'']); load([%s ''_NLTSimu.mat'']); end','jobid','jobid');
                out{end+1,1}='end';
                out{end+1,1}='%% Plot results';
                out{end+1,1}='if NLTprnt && exist(''sim_flag'',''var'')==1 && exist(''v'',''var'')==1 && exist(''Nph'',''var'')==1 && exist(''f'',''var'')==1 && exist(''jobid'',''var'')==1';
                out{end+1,1}='  if sim_flag>=1;plot_td_sim_fun(v,Nph,sim_flag,data_t_sim,time_cl_brkr,jobid);end';
                out{end+1,1}='  if sim_flag==0; plot_freq_scan_fun(v,Nph,f,jobid);end';
                out{end+1,1}='end';
                out{end+1,1}='';
                out{end+1,1}='%%%% Save all plots';
                out{end+1,1}='if Figsave';
                out{end+1,1}='  FolderName = fullfile(currPath,''plots'');   ';
                out{end+1,1}=sprintf('  mkdir(FolderName)');
                out{end+1,1}='  FigList = findobj(allchild(0), ''flat'', ''Type'', ''figure'');';
                out{end+1,1}='  for iFig = 1:length(FigList)';
                out{end+1,1}='    FigHandle = FigList(iFig);';
                out{end+1,1}='    FigName   = get(FigHandle, ''Name'');';
                out{end+1,1}='    try';
                out{end+1,1}='      if ~strcmp(FigName,''LineCableLab''); savefig(FigHandle, fullfile(FolderName,[FigName ''.fig''])); end';
                out{end+1,1}='    end';
                out{end+1,1}='  end';
                out{end+1,1}='end';
                out{end+1,1}='';
                out{end+1,1}='%%%% Export line models';
                % out{end+1,1}=sprintf('BFTOL = %d; %%%% Bodefit tolerance in DB',app.BFTOLdBEditField.Value);
                out{end+1,1}=sprintf('VFopts.errtgt = %6.6f; %%%% Vector-fitting tolerance in pu',app.VFTOLpuEditField.Value);
                out{end+1,1}=sprintf('VFopts.Npoles = [%d %d]; %%%% Number of poles',app.MinPolesEditField.Value,app.MaxPolesEditField.Value);
                %out{end+1,1}='if (export2PCH); makeJMartiModel(f,Z,Y,Ti_dis,g_dis,Zch_mod,line_length,VFTOL,BFTOL,jobid,currPath); end';
                out{end+1,1}='if (export2ULM); makeULModel3(Z,Y,f,line_length,Ti_dis,g_dis,VFopts,jobid,currPath); end';
                out{end+1,1}='if (export2EMTP); punch2emtp(f,Z,Y,line_length,jobid,currPath); end';
                out{end+1,1}='if (export2XML); makeATPXML(f,Z,Y,jobid,currPath); end';
                outstr=strjoin(out, '\n') ;
                fid = fopen(fname,'wt');
                fprintf(fid, outstr);
                fclose(fid);
                results=true;

            end
          
        end

        function [out] = parseSimuTypeFlag(app)
            simu = app.SimulationtypeDropDown.Value;
            allNames = {app.simuTypes.DisplayName};
            allFlags = {app.simuTypes.Flag};
            idx = find(strcmp(allNames, simu));
            out=allFlags{idx};
        end

        function [outZ, outZflag, outY, outYflag] = parseZYformula(app)
            Zf=app.ImpedanceDropDown.Value;
            allNames = {app.formulasZ.DisplayName};
            allVars = {app.formulasZ.VarName};
            allFlags = {app.formulasZ.optName};
            idx = find(strcmp(allNames, Zf));
            outZ=allVars{idx};
            outZflag=allFlags{idx};

            Yf=app.AdmittanceDropDown.Value;
            allNames = {app.formulasY.DisplayName};
            allVars = {app.formulasY.VarName};
            allFlags = {app.formulasY.optName};
            idx = find(strcmp(allNames, Yf));
            outY=allVars{idx};
            outYflag=allFlags{idx};
        end
        
        function [outZ, outZflag, outY, outYflag] = parseOtherZY(app)
            outZ=[];
            outZflag=[];
            outY=[];
            outYflag=[];

            try
                Zf=app.OtherZListBox.Value;
                allNames = {app.formulasZ.DisplayName};
                allVars = {app.formulasZ.VarName};
                allFlags = {app.formulasZ.optName};
                [isMatch, matchIndex] = ismember(Zf, allNames);
                outZ=allVars(matchIndex);
                outZflag=allFlags(matchIndex);

                Yf=app.OtherYListBox.Value;
                allNames = {app.formulasY.DisplayName};
                allVars = {app.formulasY.VarName};
                allFlags = {app.formulasY.optName};
                [isMatch, matchIndex] = ismember(Yf, allNames);
                outY=allVars(matchIndex);
                outYflag=allFlags(matchIndex);
            end
        end

        function out = getFDmodel(app)
            choice=app.FDsoilDropDown.Value;
            idcs=strfind(choice,'-');
            out=str2num(choice(1:idcs(1)-1));
        end

        function out = getModalTransfmodel(app)
            choice=app.ModaldecompositionDropDown.Value;
            idcs=strfind(choice,'-');
            out=str2num(choice(1:idcs(1)-1));
        end

        
        function tableDataNumeric = cell2matButNotStupid(app, tableData)
            tableDataNumeric=[];

            % Check for empty table
            if isempty(tableData)
                return; % Bail out, nothing to validate here
            end

            % % Convert cell array to numeric array, setting NaN for empty cells
            % tableDataNumeric = NaN(size(tableData));
            % for i = 1:numel(tableData)
            %     if ~isempty(tableData{i})
            %         tableDataNumeric(i) = tableData{i};
            %     end
            % end

            % Get the size of the table
            [numRows, numCols] = size(tableData);

            % Initialize the numeric array with NaNs
            tableDataNumeric = NaN(numRows, numCols);

            % Loop through each element in the table
            for row = 1:numRows
                for col = 1:numCols
                    % Extract the table entry
                    cellValue = tableData{row, col};
                    % Check if the cell is not empty
                    if ~isempty(cellValue)
                        % Assign the value to the numeric array
                        tableDataNumeric(row, col) = cellValue;
                    end
                end
            end

        end

        
        function [] = defineZChoices(app)
            i=1;
            app.formulasZ(i).DisplayName='Carson';
            app.formulasZ(i).VarName='Ztot_Carson';
            app.formulasZ(i).optName='Carson';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Noda';
            app.formulasZ(i).VarName='Ztot_Noda';
            app.formulasZ(i).optName='Noda';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Deri';
            app.formulasZ(i).VarName='Ztot_Deri';
            app.formulasZ(i).optName='Deri';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Alvarado';
            app.formulasZ(i).VarName='Ztot_AlBe';
            app.formulasZ(i).optName='Alvarado';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Sunde';
            app.formulasZ(i).VarName='Ztot_Sunde';
            app.formulasZ(i).optName='Sunde';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Semlyen';
            app.formulasZ(i).VarName='Ztot_Semlyen';
            app.formulasZ(i).optName='Semlyen';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Pettersson';
            app.formulasZ(i).VarName='Ztot_Pettersson';
            app.formulasZ(i).optName='Pettersson';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Wise';
            app.formulasZ(i).VarName='Ztot_Wise';
            app.formulasZ(i).optName='Wise';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Kikuchi';
            app.formulasZ(i).VarName='Ztot_Kik';
            app.formulasZ(i).optName='Kikuchi';
            app.formulasZ(i).Type=1;
            i=i+1;
            app.formulasZ(i).DisplayName='Nakagawa (overhead, 2 layers)';
            app.formulasZ(i).VarName='Ztot_Naka2La';
            app.formulasZ(i).optName='Naka2Layers';
            app.formulasZ(i).Type=999;
            i=i+1;
            app.formulasZ(i).DisplayName='Papadopoulos (underground)';
            app.formulasZ(i).VarName='Ztot_Papad';
            app.formulasZ(i).optName='Papadopoulos';
            app.formulasZ(i).Type=2;
            i=i+1;
            app.formulasZ(i).DisplayName='Pollaczek (underground)';
            app.formulasZ(i).VarName='Ztot_Pol';
            app.formulasZ(i).optName='Pollaczek';
            app.formulasZ(i).Type=2;
            i=i+1;
            app.formulasZ(i).DisplayName='Xue (underground)';
            app.formulasZ(i).VarName='Ztot_Xue';
            app.formulasZ(i).optName='Xue';
            app.formulasZ(i).Type=2;
            i=i+1;
            app.formulasZ(i).DisplayName='Martins-Papadopoulos-Chrysochos (overhead-underground)';
            app.formulasZ(i).VarName='Ztot_OverUnder';
            app.formulasZ(i).optName='OverUnder';
            app.formulasZ(i).Type=3;
            i=i+1;
            app.formulasZ(i).DisplayName='Carson-Pollaczek (overhead-underground)';
            app.formulasZ(i).VarName='Ztot_CarsonPol';
            app.formulasZ(i).optName='CarsonPol';
            app.formulasZ(i).Type=3;
        end

        function [] = defineYChoices(app)
            i=1;
            app.formulasY(i).DisplayName='Images';
            app.formulasY(i).VarName='Ytot_Imag';
            app.formulasY(i).optName='Images';
            app.formulasY(i).Type=1;
            i=i+1;
            app.formulasY(i).DisplayName='Pettersson';
            app.formulasY(i).VarName='Ytot_Pet';
            app.formulasY(i).optName='Pettersson';
            app.formulasY(i).Type=1;
            i=i+1;
            app.formulasY(i).DisplayName='Wise';
            app.formulasY(i).VarName='Ytot_Wise';
            app.formulasY(i).optName='Wise';
            app.formulasY(i).Type=1;
            i=i+1;
            app.formulasY(i).DisplayName='Kikuchi';
            app.formulasY(i).VarName='Ytot_Kik';
            app.formulasY(i).optName='Kikuchi';
            app.formulasY(i).Type=1;
            i=i+1;
            app.formulasY(i).DisplayName='Papadopoulos (overhead, 2 layers)';
            app.formulasY(i).VarName='Ytot_Papad2La';
            app.formulasY(i).optName='Papad2Layers';
            app.formulasY(i).Type=999;
            i=i+1;
            app.formulasY(i).DisplayName='Papadopoulos (underground)';
            app.formulasY(i).VarName='Ytot_Papad';
            app.formulasY(i).optName='Papadopoulos';
            app.formulasY(i).Type=2;
            i=i+1;
            app.formulasY(i).DisplayName='Xue (underground)';
            app.formulasY(i).VarName='Ytot_Xue';
            app.formulasY(i).optName='Xue';
            app.formulasY(i).Type=2;
            i=i+1;
            app.formulasY(i).DisplayName='Martins-Papadopoulos-Chrysochos (overhead-underground)';
            app.formulasY(i).VarName='Ytot_OverUnder';
            app.formulasY(i).optName='OverUnder';
            app.formulasY(i).Type=3;
            i=i+1;
            app.formulasY(i).DisplayName='Martins-Papadopoulos-Chrysochos (overhead-underground, classical TL)';
            app.formulasY(i).VarName='Ytot_OverUnderClassicalTL';
            app.formulasY(i).optName='OverUnderClassicalTL';
            app.formulasY(i).Type=3;
        end

        function [] = defineSimuChoices(app)
            i=1;
            app.simuTypes(i).DisplayName='Frequency scan';
            app.simuTypes(i).Flag=0;
            i=i+1;
            app.simuTypes(i).DisplayName='Sinusoidal voltage';
            app.simuTypes(i).Flag=1;
            i=i+1;
            app.simuTypes(i).DisplayName='Energization';
            app.simuTypes(i).Flag=2;
            i=i+1;
            app.simuTypes(i).DisplayName='Step response';
            app.simuTypes(i).Flag=3;
            i=i+1;
            app.simuTypes(i).DisplayName='Double exp. voltage (1.2/50 μs)';
            app.simuTypes(i).Flag=4;
            i=i+1;
            app.simuTypes(i).DisplayName='Custom voltage';
            app.simuTypes(i).Flag=5;
            i=i+1;
            app.simuTypes(i).DisplayName='Three-phase voltage';
            app.simuTypes(i).Flag=6;
            i=i+1;
            app.simuTypes(i).DisplayName='LI current';
            app.simuTypes(i).Flag=71;
            i=i+1;
            app.simuTypes(i).DisplayName='A50 current';
            app.simuTypes(i).Flag=72;
            i=i+1;
            app.simuTypes(i).DisplayName='F5 current';
            app.simuTypes(i).Flag=73;
            i=i+1;
            app.simuTypes(i).DisplayName='F95 current';
            app.simuTypes(i).Flag=74;
            i=i+1;
            app.simuTypes(i).DisplayName='S5 current';
            app.simuTypes(i).Flag=75;
            i=i+1;
            app.simuTypes(i).DisplayName='S95 current';
            app.simuTypes(i).Flag=76;

            app.SimulationtypeDropDown.Items={app.simuTypes(:).DisplayName};

        end

        function [] = redefImpedancesTable(app)
            % Adjust terminal impedance table
            % Extract PhaseNum from the app's UITable data
            phaseNum = str2double(app.UITable.Data.PhaseNum);

            % Find unique values in PhaseNum that are not zero
            uniquePhaseNum = unique(phaseNum(phaseNum ~= 0));

            % Number of unique non-zero values
            N = length(uniquePhaseNum);

            % Create the new table with N rows and 3 columns
            % Column 1: Sequential values from 1 to N
            % Columns 2 and 3: Filled with 99999
            newTableData = [sort(uniquePhaseNum), repmat(99999, N, 2)];

            % Convert to table
            newTable = array2table(string(newTableData), 'VariableNames', {'PhaseNum', 'SendZ', 'RecZ'});

            app.UITableTermImpedances.Data=newTable;
        end
        
        function [] = exportCrossSection2XML(app,path)
            %output file name
            jobid=app.JobIDEditField.Value;
            fname=fullfile(path,['ATPDraw_' jobid '.xml']);

            % DECLARE SOME USEFUL CONSTANTS AND VARIABLES
            DEFTSTART=-1;
            DEFTSTOP=1000;
            freq=60;
            Leq = app.LinelengthmEditField.Value;
            rho=app.ResistivitymEditField.Value;

            linecable_data = cell2matButNotStupid(app,app.UITable.Data);

            numphases=size(linecable_data,1);
            TopLeftX=200; % 200 + 240 LCCs per row give the optimal layout
            TopLeftY=0; %will need this later to place the LCCs

            % writes ATPdraw header
            outstruct.ApplicationAttribute='ATPDraw';
            outstruct.VersionAttribute=7;
            outstruct.VersionXMLAttribute=1;
            outstruct.header.TimestepAttribute=1e-6;
            outstruct.header.TmaxAttribute=.1;
            outstruct.header.XOPTAttribute=0;
            outstruct.header.COPTAttribute=0;
            outstruct.header.SysFreqAttribute=freq;
            outstruct.header.TopLeftXAttribute=TopLeftX;
            outstruct.header.TopLeftYAttribute=TopLeftY;

            % now the damn components section
            offsetX=80;
            offsetY=360;
            tower=1;
            LCC_per_row=20; %240;
            rowcount=1; %counts number of times that LCC_per_row is reached
            colcount=1; % will be reset to 1 every LCC_per_row
            conn_idx=1;

            numblocks=1;

            for i=1:numblocks
                outstruct.objects.comp(i).NameAttribute='LCC';
                outstruct.objects.comp(i).IdAttribute=string([jobid '_' num2str(i)]);
                outstruct.objects.comp(i).CapanglAttribute=90;
                outstruct.objects.comp(i).CapPosXAttribute=-10;
                outstruct.objects.comp(i).CapPosYAttribute=-25;
                outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+(colcount*offsetX);
                outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY);

                colcount=colcount+1;
                thiscaption='';
                outstruct.objects.comp(i).CaptionAttribute=thiscaption;

                if mod(i,LCC_per_row)==0
                    colcount=1;
                    rowcount=rowcount+1;
                end

                outstruct.objects.comp(i).comp_content.NumPhasesAttribute=numphases;
                outstruct.objects.comp(i).comp_content.IconAttribute='default';
                outstruct.objects.comp(i).comp_content.SinglePhaseIconAttribute='true';

                %EACH ONE OF THESE BIG BOYS NEEDS Nph NODES
                y0=-20;
                for k=1:numphases %input nodes
                    y0=y0+10;
                    outstruct.objects.comp(i).comp_content.node(k).NameAttribute=string(sprintf('IN%d',k));
                    outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string(sprintf('C%dSND',k));
                    % outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string(sprintf('C%d%04d',k,i));
                    outstruct.objects.comp(i).comp_content.node(k).UserNamedAttribute='true';
                    %         outstruct.objects.comp(i).comp_content.node(k).NumPhasesAttribute=1;
                    outstruct.objects.comp(i).comp_content.node(k).KindAttribute=k;
                    outstruct.objects.comp(i).comp_content.node(k).PosXAttribute=-20;
                    outstruct.objects.comp(i).comp_content.node(k).PosYAttribute=y0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosXAttribute=0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosYAttribute=0;
                end

                y0=-20;
                for k=numphases+1:2*numphases %output nodes
                    w=k-numphases;
                    y0=y0+10;
                    outstruct.objects.comp(i).comp_content.node(k).NameAttribute=string(sprintf('OUT%d',w));
                    outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string(sprintf('C%dRCV',w));
                    % outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string(sprintf('C%d%04d',w,i+1));
                    outstruct.objects.comp(i).comp_content.node(k).UserNamedAttribute='true';
                    %         outstruct.objects.comp(i).comp_content.node(k).NumPhasesAttribute=1;
                    outstruct.objects.comp(i).comp_content.node(k).KindAttribute=w;
                    outstruct.objects.comp(i).comp_content.node(k).PosXAttribute=20;
                    outstruct.objects.comp(i).comp_content.node(k).PosYAttribute=y0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosXAttribute=0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosYAttribute=0;
                end

                % yeah this fucking sucks and probably can be done better
                outstruct.objects.comp(i).comp_content.data(1).NameAttribute='Length';
                outstruct.objects.comp(i).comp_content.data(1).ValueAttribute=Leq; %yep, it's here
                outstruct.objects.comp(i).comp_content.data(2).NameAttribute='Freq';
                outstruct.objects.comp(i).comp_content.data(2).ValueAttribute=freq; %yep, it's here
                outstruct.objects.comp(i).comp_content.data(3).NameAttribute='Grnd resis';
                outstruct.objects.comp(i).comp_content.data(3).ValueAttribute=rho; %yep, it's here
                %actual LCC data
                outstruct.objects.comp(i).LCC.NumPhasesAttribute=numphases;
                outstruct.objects.comp(i).LCC.IconLengthAttribute='true';
                outstruct.objects.comp(i).LCC.LineCablePipeAttribute=2; %probably 2=single core
                outstruct.objects.comp(i).LCC.ModelTypeAttribute=1; %0 = bergeron, 1 = pi
                outstruct.objects.comp(i).LCC.cable_header.InAirGrndAttribute=1;
                outstruct.objects.comp(i).LCC.cable_header.MatrixOutputAttribute='true';
                for k=1:numphases
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).NumCondAttribute=1;
                    if isnan(linecable_data(k,8))
                        rrout=linecable_data(k,5);
                    else
                        rrout=linecable_data(k,8);
                    end
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).RoutAttribute=rrout;
                    horzdist=linecable_data(k,2);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).PosXAttribute=horzdist;
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).PosYAttribute=linecable_data(k,3);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.RinAttribute=linecable_data(k,4);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.RoutAttribute=linecable_data(k,5);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.rhoAttribute=linecable_data(k,6);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.muCAttribute=linecable_data(k,7);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.muIAttribute=linecable_data(k,9);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.epsIAttribute=linecable_data(k,10);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.CextAttribute=0;
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.GextAttribute=0;
                end

                % writes ATPDraw variables
                outstruct.variables.NumSimAttribute=1;
                outstruct.variables.IOPCVPAttribute=0;
                outstruct.variables.UseParserAttribute='false';
                % datastr={}; %does not seem to work when loading from XML
                % datastr{1,1}='RT=10.0 ';
                % datastr{end+1,1}='RC=1.0E-5 ';
                % outstruct.variables.VarStr = strjoin(datastr,char(10));

                %output to XML and fix non-conforming tags
                writestruct(outstruct, fname,'StructNodeName','project');
                if isfile(fname)
                    msg=sprintf('File %s created sucessfully!',['ATPDraw_' jobid '.xml']);
                    f = uimsgbox(app, msg, 'It works!','success');
                end
                % func_fix_text_labels(fname, fname)
                % func_fix_var_tags(fname, fname)
            end
        end

        
        function [] = redefTargetOptions(app)
            phaseNum = str2double(app.UITable.Data.PhaseNum);

            % Find unique values in PhaseNum that are not zero
            uniquePhaseNum = unique(phaseNum(phaseNum ~= 0));

            app.TargetphaseDropDown.Items=cellstr(num2str(uniquePhaseNum));
            app.ShieldwiresListBox.Items=cellstr(num2str(uniquePhaseNum));

            
        end
        

        function [] = buildEMISimu(app,path)
            addpath('emi_simu_funs')

            % Read and pre-process geometry
            jobid=app.JobIDEditField.Value;

            freq=app.FrequencyHzEditField.Value;

            LINE_FILE_NAME = app.SourceCSVEditField.Value;
            src=readmatrix(LINE_FILE_NAME);

            PIPE_FILE_NAME = app.TargetCSVEditField.Value;
            tgt=readmatrix(PIPE_FILE_NAME);

            rho=app.ResistivitymEditField.Value; %soil resistivity

            minTargetLength=app.SubdivisionmEditField.Value; %pipeline will be subdivided every minTargetLength meters

            [GeometricData, LCCData] = calcCouplingRegionsParams(src,tgt,minTargetLength); %don't try to understand what this is, it's just... WEIRD
            G=calcGreenFuns(src,tgt,rho);

            src=GeometricData(:,1:2);
            src=src(LCCData(:,4)==1,:);

            tgt=GeometricData(:,3:4);
            
            % Cross-section
            linecable_data = cell2matButNotStupid(app,app.UITable.Data);
            tgt_idx=str2double(app.TargetphaseDropDown.Value);
            linecable_data(tgt_idx,2)=nan;
            sw_idx=str2double(app.ShieldwiresListBox.Value);

            
            % BUILD AN ATPDRAW FILE DESCRIBING THE EMI CIRCUIT
            % <<<<<<<<<----------- don't change anything below this line

            % don't bother, this is for debug purposes only
            % sample= readstruct('cruz45_teste_len100_rho1000.xml', 'FileType','xml');


            %output file name
            fname=['EMISimu_' jobid '.xml'];

            numblocks=size(LCCData,1);
            numtowers=sum(LCCData(:,4));

            % DECLARE SOME USEFUL CONSTANTS AND VARIABLES
            nodeTGnd=sprintf('P%d',sw_idx(1));
            nodePGnd=sprintf('P%d',tgt_idx);
            DEFTSTART=-1;
            DEFTSTOP=1000;


            numphases=size(linecable_data,1);

            TopLeftX=200; % 200 + 240 LCCs per row give the optimal layout
            TopLeftY=0; %will need this later to place the LCCs

            % writes ATPdraw header
            outstruct.ApplicationAttribute='ATPDraw';
            outstruct.VersionAttribute=7;
            outstruct.VersionXMLAttribute=1;
            outstruct.header.TimestepAttribute=1e-6;
            outstruct.header.TmaxAttribute=.1;
            outstruct.header.XOPTAttribute=0;
            outstruct.header.COPTAttribute=0;
            outstruct.header.SysFreqAttribute=freq;
            outstruct.header.TopLeftXAttribute=TopLeftX;
            outstruct.header.TopLeftYAttribute=TopLeftY;

            % now the damn components section
            offsetX=80;
            offsetY=360;
            tower=1;
            LCC_per_row=20; %240;
            rowcount=1; %counts number of times that LCC_per_row is reached
            colcount=1; % will be reset to 1 every LCC_per_row
            conn_idx=1;

            for i=1:numblocks
                deq=LCCData(i,1);
                Leq=LCCData(i,2);
                hastower=logical(LCCData(i,4));

                outstruct.objects.comp(i).NameAttribute='LCC';
                outstruct.objects.comp(i).IdAttribute=string(['LCCSEC' num2str(i)]);
                outstruct.objects.comp(i).CapanglAttribute=90;
                outstruct.objects.comp(i).CapPosXAttribute=-10;
                outstruct.objects.comp(i).CapPosYAttribute=-25;
                outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+(colcount*offsetX);
                outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY);
                colcount=colcount+1;
                thiscaption='';
                if hastower
                    thiscaption=string(['T' num2str(tower)]);
                    % tower resistors
                    towRstruct.objects.comp(tower).NameAttribute='RESISTOR';
                    towRstruct.objects.comp(tower).CaptionAttribute='';%sprintf('T%04d',tower);
                    towRstruct.objects.comp(tower).IdAttribute=[];
                    towRstruct.objects.comp(tower).LCC=[];
                    towRstruct.objects.comp(tower).CapanglAttribute=0;
                    towRstruct.objects.comp(tower).CapPosXAttribute=20;
                    towRstruct.objects.comp(tower).CapPosYAttribute=5;
                    towRstruct.objects.comp(tower).comp_content.AngleAttribute=270;
                    towRstruct.objects.comp(tower).comp_content.PosXAttribute=TopLeftX+((colcount-1)*offsetX)+offsetX-20;
                    towRstruct.objects.comp(tower).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY)+numphases*10+60;
                    towRstruct.objects.comp(tower).comp_content.OutputAttribute=1; %1 for current
                    towRstruct.objects.comp(tower).comp_content.IconAttribute='default';
                    towRstruct.objects.comp(tower).comp_content.node(1).NameAttribute='From';
                    towRstruct.objects.comp(tower).comp_content.node(1).ValueAttribute=sprintf('T%04d',tower);  %%%%%%%%%%%%%%% CHECKME FOR GENERALIZATION
                    towRstruct.objects.comp(tower).comp_content.node(1).UserNamedAttribute=true;
                    towRstruct.objects.comp(tower).comp_content.node(1).KindAttribute=1;
                    towRstruct.objects.comp(tower).comp_content.node(1).PosXAttribute=-20;
                    towRstruct.objects.comp(tower).comp_content.node(1).PosYAttribute=0;
                    towRstruct.objects.comp(tower).comp_content.node(1).NamePosXAttribute=10;
                    towRstruct.objects.comp(tower).comp_content.node(1).NamePosYAttribute=5;
                    towRstruct.objects.comp(tower).comp_content.node(2).NameAttribute='To';
                    towRstruct.objects.comp(tower).comp_content.node(2).ValueAttribute='';
                    towRstruct.objects.comp(tower).comp_content.node(2).UserNamedAttribute=false;
                    towRstruct.objects.comp(tower).comp_content.node(2).KindAttribute=1;
                    towRstruct.objects.comp(tower).comp_content.node(2).PosXAttribute=20;
                    towRstruct.objects.comp(tower).comp_content.node(2).PosYAttribute=0;
                    towRstruct.objects.comp(tower).comp_content.node(2).NamePosXAttribute=10;
                    towRstruct.objects.comp(tower).comp_content.node(2).NamePosYAttribute=5;
                    towRstruct.objects.comp(tower).comp_content.node(2).GroundAttribute=1;
                    towRstruct.objects.comp(tower).comp_content.data(1).NameAttribute='R';
                    towRstruct.objects.comp(tower).comp_content.data(1).UnitAttribute='Ohm';
                    towRstruct.objects.comp(tower).comp_content.data(1).ValueAttribute='RT';
                    % current measuring probe
                    towSWstruct.objects.comp(tower).NameAttribute='SWMEAS';
                    towSWstruct.objects.comp(tower).CaptionAttribute='';%'→ TACS';
                    towSWstruct.objects.comp(tower).IdAttribute=[];
                    towSWstruct.objects.comp(tower).LCC=[];
                    towSWstruct.objects.comp(tower).CapanglAttribute=0;
                    towSWstruct.objects.comp(tower).CapPosXAttribute=10;
                    towSWstruct.objects.comp(tower).CapPosYAttribute=5;
                    towSWstruct.objects.comp(tower).comp_content.AngleAttribute=270;
                    towSWstruct.objects.comp(tower).comp_content.PosXAttribute=TopLeftX+((colcount-1)*offsetX)+offsetX-20;
                    towSWstruct.objects.comp(tower).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY)+numphases*10+20;
                    towSWstruct.objects.comp(tower).comp_content.IconAttribute='default';
                    towSWstruct.objects.comp(tower).comp_content.node(1).NameAttribute='SWF';
                    towSWstruct.objects.comp(tower).comp_content.node(1).ValueAttribute=sprintf('%s%04d',nodeTGnd,i);  %%%%%%%%%%%%%%% CHECKME FOR GENERALIZATION
                    towSWstruct.objects.comp(tower).comp_content.node(1).UserNamedAttribute=true;
                    towSWstruct.objects.comp(tower).comp_content.node(1).KindAttribute=1;
                    towSWstruct.objects.comp(tower).comp_content.node(1).PosXAttribute=-20;
                    towSWstruct.objects.comp(tower).comp_content.node(1).PosYAttribute=0;
                    towSWstruct.objects.comp(tower).comp_content.node(1).NamePosXAttribute=10;
                    towSWstruct.objects.comp(tower).comp_content.node(1).NamePosYAttribute=5;
                    towSWstruct.objects.comp(tower).comp_content.node(2).NameAttribute='SWT';
                    towSWstruct.objects.comp(tower).comp_content.node(2).ValueAttribute=sprintf('T%04d',tower);
                    towSWstruct.objects.comp(tower).comp_content.node(2).UserNamedAttribute=false;
                    towSWstruct.objects.comp(tower).comp_content.node(2).KindAttribute=1;
                    towSWstruct.objects.comp(tower).comp_content.node(2).PosXAttribute=20;
                    towSWstruct.objects.comp(tower).comp_content.node(2).PosYAttribute=0;
                    towSWstruct.objects.comp(tower).comp_content.node(2).NamePosXAttribute=10;
                    towSWstruct.objects.comp(tower).comp_content.node(2).NamePosYAttribute=5;
                    tower=tower+1;
                end
                outstruct.objects.comp(i).CaptionAttribute=thiscaption;


                % pipeline coating resistances
                pipRstruct.objects.comp(i).NameAttribute='RESISTOR';
                pipRstruct.objects.comp(i).CaptionAttribute='';
                pipRstruct.objects.comp(i).IdAttribute=[];
                pipRstruct.objects.comp(i).LCC=[];
                pipRstruct.objects.comp(i).CapanglAttribute=0;
                pipRstruct.objects.comp(i).CapPosXAttribute=20;
                pipRstruct.objects.comp(i).CapPosYAttribute=5;
                pipRstruct.objects.comp(i).comp_content.AngleAttribute=270;
                pipRstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+((colcount-1)*offsetX)+20;
                pipRstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY)+numphases*10+20;
                pipRstruct.objects.comp(i).comp_content.OutputAttribute=0;
                pipRstruct.objects.comp(i).comp_content.IconAttribute='default';
                pipRstruct.objects.comp(i).comp_content.node(1).NameAttribute='From';
                pipRstruct.objects.comp(i).comp_content.node(1).ValueAttribute=sprintf('%s%04d',nodePGnd,i);  %%%%%%%%%%%%%%% CHECKME FOR GENERALIZATION
                pipRstruct.objects.comp(i).comp_content.node(1).UserNamedAttribute=true;
                pipRstruct.objects.comp(i).comp_content.node(1).KindAttribute=1;
                pipRstruct.objects.comp(i).comp_content.node(1).PosXAttribute=-20;
                pipRstruct.objects.comp(i).comp_content.node(1).PosYAttribute=0;
                pipRstruct.objects.comp(i).comp_content.node(1).NamePosXAttribute=10;
                pipRstruct.objects.comp(i).comp_content.node(1).NamePosYAttribute=5;
                pipRstruct.objects.comp(i).comp_content.node(2).NameAttribute='To';
                pipRstruct.objects.comp(i).comp_content.node(2).ValueAttribute=sprintf('G%04d',i);
                pipRstruct.objects.comp(i).comp_content.node(2).UserNamedAttribute=false;
                pipRstruct.objects.comp(i).comp_content.node(2).KindAttribute=1;
                pipRstruct.objects.comp(i).comp_content.node(2).PosXAttribute=20;
                pipRstruct.objects.comp(i).comp_content.node(2).PosYAttribute=0;
                pipRstruct.objects.comp(i).comp_content.node(2).NamePosXAttribute=10;
                pipRstruct.objects.comp(i).comp_content.node(2).NamePosYAttribute=5;
                pipRstruct.objects.comp(i).comp_content.node(2).GroundAttribute=0;
                pipRstruct.objects.comp(i).comp_content.data.NameAttribute='R';
                pipRstruct.objects.comp(i).comp_content.data.UnitAttribute='Ohm';
                Rout=linecable_data(tgt_idx,5);
                RhoCoat=app.CoatingresistivitymEditField.Value;
                if isnan(linecable_data(tgt_idx,8))
                    ThickCoat=0;
                else
                    ThickCoat=linecable_data(tgt_idx,8)-linecable_data(tgt_idx,5);
                end
                RelPermCoat=linecable_data(tgt_idx,10);

                ZC=shuntImpedanceSES(freq,Rout,RhoCoat,ThickCoat,RelPermCoat,Leq);
                pipRstruct.objects.comp(i).comp_content.data.ValueAttribute=real(ZC);

                % pipeline external GPR source
                pipGstruct.objects.comp(i).NameAttribute='TACSSOUR';
                pipGstruct.objects.comp(i).CaptionAttribute='';
                pipGstruct.objects.comp(i).IdAttribute=[];
                pipGstruct.objects.comp(i).LCC=[];
                pipGstruct.objects.comp(i).CapanglAttribute=0;

                pipGstruct.objects.comp(i).CapPosXAttribute=20;
                pipGstruct.objects.comp(i).CapPosYAttribute=5;
                pipGstruct.objects.comp(i).comp_content.AngleAttribute=90;
                pipGstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+((colcount-1)*offsetX)+20;
                pipGstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+(rowcount*offsetY)+numphases*10+60;
                pipGstruct.objects.comp(i).comp_content.IconAttribute='default';
                pipGstruct.objects.comp(i).comp_content.node(1).NameAttribute='TACS';
                pipGstruct.objects.comp(i).comp_content.node(1).ValueAttribute=sprintf('G%04d',i);  %%%%%%%%%%%%%%% CHECKME FOR GENERALIZATION
                pipGstruct.objects.comp(i).comp_content.node(1).UserNamedAttribute=true;
                pipGstruct.objects.comp(i).comp_content.node(1).KindAttribute=1;
                pipGstruct.objects.comp(i).comp_content.node(1).PosXAttribute=20;
                pipGstruct.objects.comp(i).comp_content.node(1).PosYAttribute=0;
                pipGstruct.objects.comp(i).comp_content.node(1).NamePosXAttribute=10;
                pipGstruct.objects.comp(i).comp_content.node(1).NamePosYAttribute=5;
                pipGstruct.objects.comp(i).comp_content.data(1).NameAttribute='U/I';
                pipGstruct.objects.comp(i).comp_content.data(1).UnitAttribute='';
                pipGstruct.objects.comp(i).comp_content.data(1).ValueAttribute=0;
                pipGstruct.objects.comp(i).comp_content.data(2).NameAttribute='TStart';
                pipGstruct.objects.comp(i).comp_content.data(2).UnitAttribute='s';
                pipGstruct.objects.comp(i).comp_content.data(2).ValueAttribute=-1;
                pipGstruct.objects.comp(i).comp_content.data(3).NameAttribute='TStop';
                pipGstruct.objects.comp(i).comp_content.data(3).UnitAttribute='s';
                pipGstruct.objects.comp(i).comp_content.data(3).ValueAttribute=DEFTSTOP;


                for j=1:numphases
                    outstruct.objects.conn(conn_idx).conn_content.NumPhasesAttribute=1;
                    outstruct.objects.conn(conn_idx).conn_content.Pos1XAttribute=TopLeftX+((colcount-1)*offsetX)+20;
                    outstruct.objects.conn(conn_idx).conn_content.Pos1YAttribute=TopLeftY+(rowcount*offsetY)-20+j*10;
                    outstruct.objects.conn(conn_idx).conn_content.Pos2XAttribute=TopLeftX+((colcount-1)*offsetX)+offsetX-20;
                    outstruct.objects.conn(conn_idx).conn_content.Pos2YAttribute=TopLeftY+(rowcount*offsetY)-20+j*10;
                    if j == tgt_idx
                        thiscolor='16711680'; %blue
                    elseif ismember(j,sw_idx)
                        thiscolor='65280'; %green
                    else
                        thiscolor='0'; %dont know what to do, paint it black
                    end
                   
                    outstruct.objects.conn(conn_idx).conn_content.ColorAttribute=thiscolor;
                    conn_idx=conn_idx+1;
                end

                if mod(i,LCC_per_row)==0
                    colcount=1;
                    rowcount=rowcount+1;
                end

                outstruct.objects.comp(i).comp_content.NumPhasesAttribute=numphases;
                outstruct.objects.comp(i).comp_content.IconAttribute='default';
                outstruct.objects.comp(i).comp_content.SinglePhaseIconAttribute='true';

                %EACH ONE OF THESE BIG BOYS NEEDS Nph NODES
                y0=-20;
                for k=1:numphases %input nodes
                    y0=y0+10;
                    outstruct.objects.comp(i).comp_content.node(k).NameAttribute=string(sprintf('IN%d',k));
                    outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string(sprintf('P%d%04d',k,i-1));
                    outstruct.objects.comp(i).comp_content.node(k).UserNamedAttribute='true';
                    %         outstruct.objects.comp(i).comp_content.node(k).NumPhasesAttribute=1;
                    outstruct.objects.comp(i).comp_content.node(k).KindAttribute=k;
                    outstruct.objects.comp(i).comp_content.node(k).PosXAttribute=-20;
                    outstruct.objects.comp(i).comp_content.node(k).PosYAttribute=y0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosXAttribute=0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosYAttribute=0;
                end

                y0=-20;
                for k=numphases+1:2*numphases %output nodes
                    w=k-numphases;
                    y0=y0+10;
                    outstruct.objects.comp(i).comp_content.node(k).NameAttribute=string(sprintf('OUT%d',w));
                    outstruct.objects.comp(i).comp_content.node(k).ValueAttribute=string(sprintf('P%d%04d',w,i)); %string([linecable_data{w,12} sprintf('%04d',i+1)]);
                    outstruct.objects.comp(i).comp_content.node(k).UserNamedAttribute='true';
                    %         outstruct.objects.comp(i).comp_content.node(k).NumPhasesAttribute=1;
                    outstruct.objects.comp(i).comp_content.node(k).KindAttribute=w;
                    outstruct.objects.comp(i).comp_content.node(k).PosXAttribute=20;
                    outstruct.objects.comp(i).comp_content.node(k).PosYAttribute=y0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosXAttribute=0;
                    outstruct.objects.comp(i).comp_content.node(k).NamePosYAttribute=0;
                end

                % yeah this fucking sucks and probably can be done better
                outstruct.objects.comp(i).comp_content.data(1).NameAttribute='Length';
                outstruct.objects.comp(i).comp_content.data(1).ValueAttribute=Leq; %yep, it's here
                outstruct.objects.comp(i).comp_content.data(2).NameAttribute='Freq';
                outstruct.objects.comp(i).comp_content.data(2).ValueAttribute=freq; %yep, it's here
                outstruct.objects.comp(i).comp_content.data(3).NameAttribute='Grnd resis';
                outstruct.objects.comp(i).comp_content.data(3).ValueAttribute=rho; %yep, it's here
                %actual LCC data
                outstruct.objects.comp(i).LCC.NumPhasesAttribute=numphases;
                outstruct.objects.comp(i).LCC.IconLengthAttribute='true';
                outstruct.objects.comp(i).LCC.LineCablePipeAttribute=2; %probably 2=single core
                outstruct.objects.comp(i).LCC.ModelTypeAttribute=1; %0 = bergeron, 1 = pi
                outstruct.objects.comp(i).LCC.cable_header.InAirGrndAttribute=1;
                outstruct.objects.comp(i).LCC.cable_header.MatrixOutputAttribute='true';
                for k=1:numphases
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).NumCondAttribute=1;
                    if isnan(linecable_data(k,8))
                        outstruct.objects.comp(i).LCC.cable_header.cable(k).RoutAttribute=linecable_data(k,5);
                    else
                        outstruct.objects.comp(i).LCC.cable_header.cable(k).RoutAttribute=linecable_data(k,8);
                    end
                    if isnan(linecable_data(k,2)) %modified this to autodetect what is the target conductor
                        horzdist=deq;
                    else
                        horzdist=linecable_data(k,2);
                    end
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).PosXAttribute=horzdist;
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).PosYAttribute=linecable_data(k,3);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.RinAttribute=linecable_data(k,4);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.RoutAttribute=linecable_data(k,5);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.rhoAttribute=linecable_data(k,6);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.muCAttribute=linecable_data(k,7);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.muIAttribute=linecable_data(k,9);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.epsIAttribute=linecable_data(k,10);
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.CextAttribute=0;
                    outstruct.objects.comp(i).LCC.cable_header.cable(k).conductor.GextAttribute=0;
                end
            end

            % LOCAL AND REMOTE TERMINATIONS
            i=i+1;
            if ~app.MatchwithcharacteristicimpedanceCheckBox.Value
                SendZVal=str2double(app.UITableTermImpedances.Data.SendZ);
                RecZVal=str2double(app.UITableTermImpedances.Data.RecZ);
            else
                SendZVal=1e-5*ones(numphases,1);
                RecZVal=1e-5*ones(numphases,1);
            end

            for j=1:numphases
                outstruct.objects.comp(i).NameAttribute='RESISTOR';
                outstruct.objects.comp(i).CaptionAttribute='';
                outstruct.objects.comp(i).IdAttribute=[];
                outstruct.objects.comp(i).LCC=[];
                outstruct.objects.comp(i).CapanglAttribute=0;
                outstruct.objects.comp(i).CapPosXAttribute=20;
                outstruct.objects.comp(i).CapPosYAttribute=5;
                outstruct.objects.comp(i).comp_content.AngleAttribute=0;
                outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+1*offsetX-40;
                outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+(1*offsetY)+(j-1)*10-10;
                outstruct.objects.comp(i).comp_content.OutputAttribute=0;
                outstruct.objects.comp(i).comp_content.IconAttribute='default';
                outstruct.objects.comp(i).comp_content.node(1).NameAttribute='From';
                outstruct.objects.comp(i).comp_content.node(1).ValueAttribute=sprintf('%s','');  %%%%%%%%%%%%%%% CHECKME FOR GENERALIZATION
                outstruct.objects.comp(i).comp_content.node(1).UserNamedAttribute=true;
                outstruct.objects.comp(i).comp_content.node(1).KindAttribute=1;
                outstruct.objects.comp(i).comp_content.node(1).PosXAttribute=-20;
                outstruct.objects.comp(i).comp_content.node(1).PosYAttribute=0;
                outstruct.objects.comp(i).comp_content.node(1).NamePosXAttribute=10;
                outstruct.objects.comp(i).comp_content.node(1).NamePosYAttribute=5;
                outstruct.objects.comp(i).comp_content.node(2).NameAttribute='To';
                outstruct.objects.comp(i).comp_content.node(2).ValueAttribute=sprintf('P%d%04d',j,0);
                outstruct.objects.comp(i).comp_content.node(2).UserNamedAttribute=false;
                outstruct.objects.comp(i).comp_content.node(2).KindAttribute=1;
                outstruct.objects.comp(i).comp_content.node(2).PosXAttribute=20;
                outstruct.objects.comp(i).comp_content.node(2).PosYAttribute=0;
                outstruct.objects.comp(i).comp_content.node(2).NamePosXAttribute=10;
                outstruct.objects.comp(i).comp_content.node(2).NamePosYAttribute=5;
                outstruct.objects.comp(i).comp_content.node(2).GroundAttribute=0;
                outstruct.objects.comp(i).comp_content.data.NameAttribute='R';
                outstruct.objects.comp(i).comp_content.data.UnitAttribute='Ohm';
                outstruct.objects.comp(i).comp_content.data.ValueAttribute=SendZVal(j);
                i=i+1;
            end

            for j=1:numphases
                outstruct.objects.comp(i).NameAttribute='RESISTOR';
                outstruct.objects.comp(i).CaptionAttribute='';
                outstruct.objects.comp(i).IdAttribute=[];
                outstruct.objects.comp(i).LCC=[];
                outstruct.objects.comp(i).CapanglAttribute=0;
                outstruct.objects.comp(i).CapPosXAttribute=20;
                outstruct.objects.comp(i).CapPosYAttribute=5;
                outstruct.objects.comp(i).comp_content.AngleAttribute=0;
                outstruct.objects.comp(i).comp_content.PosXAttribute= TopLeftX+((colcount-1)*offsetX)+offsetX;
                outstruct.objects.comp(i).comp_content.PosYAttribute= TopLeftY+(rowcount*offsetY)-20+j*10;
                outstruct.objects.comp(i).comp_content.OutputAttribute=0;
                outstruct.objects.comp(i).comp_content.IconAttribute='default';
                outstruct.objects.comp(i).comp_content.node(1).NameAttribute='From';
                outstruct.objects.comp(i).comp_content.node(1).ValueAttribute=sprintf('P%d%04d',j,numblocks);
                outstruct.objects.comp(i).comp_content.node(1).UserNamedAttribute=true;
                outstruct.objects.comp(i).comp_content.node(1).KindAttribute=1;
                outstruct.objects.comp(i).comp_content.node(1).PosXAttribute=-20;
                outstruct.objects.comp(i).comp_content.node(1).PosYAttribute=0;
                outstruct.objects.comp(i).comp_content.node(1).NamePosXAttribute=10;
                outstruct.objects.comp(i).comp_content.node(1).NamePosYAttribute=5;
                outstruct.objects.comp(i).comp_content.node(2).NameAttribute='To';
                outstruct.objects.comp(i).comp_content.node(2).ValueAttribute=sprintf('%s','');  %%%%%%%%%%%%%%% CHECKME FOR GENERALIZATION
                outstruct.objects.comp(i).comp_content.node(2).UserNamedAttribute=false;
                outstruct.objects.comp(i).comp_content.node(2).KindAttribute=1;
                outstruct.objects.comp(i).comp_content.node(2).PosXAttribute=20;
                outstruct.objects.comp(i).comp_content.node(2).PosYAttribute=0;
                outstruct.objects.comp(i).comp_content.node(2).NamePosXAttribute=10;
                outstruct.objects.comp(i).comp_content.node(2).NamePosYAttribute=5;
                outstruct.objects.comp(i).comp_content.node(2).GroundAttribute=0;
                outstruct.objects.comp(i).comp_content.data.NameAttribute='R';
                outstruct.objects.comp(i).comp_content.data.UnitAttribute='Ohm';
                outstruct.objects.comp(i).comp_content.data.ValueAttribute=RecZVal(j);
                i=i+1;
            end

            % ADDITIONAL CARDS
            % i=i+1; % already incremented above
            crd=0;bspac=80;
            datastr={};
            outstruct.objects.comp(i).NameAttribute='ADDITIONAL';
            outstruct.objects.comp(i).CaptionAttribute='TACS/MODELS SETUP';
            outstruct.objects.comp(i).CapanglAttribute=0;
            outstruct.objects.comp(i).CapPosXAttribute=-35;
            outstruct.objects.comp(i).CapPosYAttribute=35;
            outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+crd*2*bspac+(bspac+10);
            outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+2*bspac;
            outstruct.objects.comp(i).comp_content.IconAttribute='default';
            outstruct.objects.comp(i).comp_content.data.NameAttribute='Kind';
            outstruct.objects.comp(i).comp_content.data.UnitAttribute  ='';
            outstruct.objects.comp(i).comp_content.data.ValueAttribute  = 1; %1 = TACS
            datastr{1,1}=        'C TACS CURRENT INPUT TO MODELS';
            for t=1:numtowers
                %'91XXXXX                                                            -1.      1.E3';
                datastr{end+1,1}=sprintf('91T%04d                                                            -1.      1.E3',t);
            end
            datastr{end+1,1}='C MODELS OUTPUT INTO TACS SOURCE';
            for t=1:numtowers
                %'27XXXXX';
                datastr{end+1,1}=sprintf('27G%04d',t);
            end
            outstruct.objects.comp(i).comp_content.datastring = strjoin(datastr,char(10));

            i=i+1;crd=crd+1;
            datastr={};
            outstruct.objects.comp(i).NameAttribute='ADDITIONAL';
            outstruct.objects.comp(i).CaptionAttribute='BUNDLED PHASES';
            outstruct.objects.comp(i).CapanglAttribute=0;
            outstruct.objects.comp(i).CapPosXAttribute=-35;
            outstruct.objects.comp(i).CapPosYAttribute=35;
            outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+crd*2*bspac+(bspac+10);
            outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+2*bspac;
            outstruct.objects.comp(i).comp_content.IconAttribute='default';
            outstruct.objects.comp(i).comp_content.data.NameAttribute='Kind';
            outstruct.objects.comp(i).comp_content.data.UnitAttribute  ='';
            outstruct.objects.comp(i).comp_content.data.ValueAttribute  = 3; %3 = BRANCH
            datastr{1,1}=    'C SHORT-CIRCUIT BUNDLED PHASES';
            datastr{end+1,1}='C < n1 >< n2 ><ref1><ref2>< R  >< L  >< C  >';
            for k=1:numblocks       %'  50005A50005B              1E-6'
                for j=1:length(sw_idx)-1
                    datastr{end+1,1}=sprintf('  P%d%04dP%d%04d             1.E-5            ',sw_idx(j),k,sw_idx(j+1),k); %fucking lazy half assed solution, needs to improve for generality
                end
                % datastr{end+1,1}=sprintf('  1A%04d2A%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1A%04d3A%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1A%04d4A%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1B%04d2B%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1B%04d3B%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1B%04d4B%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1C%04d2C%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1C%04d3C%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
                % datastr{end+1,1}=sprintf('  1C%04d4C%04d             1.E-5            ',k,k); %fucking lazy half assed solution, needs to improve for generality
            end
            outstruct.objects.comp(i).comp_content.datastring = strjoin(datastr,char(10));

            i=i+1;crd=crd+1;
            datastr={};
            outstruct.objects.comp(i).NameAttribute='ADDITIONAL';
            outstruct.objects.comp(i).CaptionAttribute='REQUEST OUTPUTS';
            outstruct.objects.comp(i).CapanglAttribute=0;
            outstruct.objects.comp(i).CapPosXAttribute=-35;
            outstruct.objects.comp(i).CapPosYAttribute=35;
            outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+crd*2*bspac+(bspac+10);
            outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+2*bspac;
            outstruct.objects.comp(i).comp_content.IconAttribute='default';
            outstruct.objects.comp(i).comp_content.data.NameAttribute='Kind';
            outstruct.objects.comp(i).comp_content.data.UnitAttribute  ='';
            outstruct.objects.comp(i).comp_content.data.ValueAttribute  = 8; %8 = REQUEST
            datastr{1,1}=    'C OUTPUT NODE VOLTAGES';
            for p=1:numblocks
                datastr{end+1,1}=sprintf('00%s%04d',nodePGnd,p);
                datastr{end+1,1}=sprintf('00G%04d',p); %dont ever again forget the trailing zeroes you fucking potato
            end
            outstruct.objects.comp(i).comp_content.datastring = strjoin(datastr,char(10));


            % NOW IT'S TIME TO FACE THE MIGHTY BEAST - THE GPR MODELS OF THE DAMNED
            nodeT=sprintfc('T%04d',[1:numtowers]);
            nodeP=sprintfc('G%04d',[1:numblocks]);
            MODstr=writeGPRsourcesModels(G,nodeT,nodeP,numtowers,numblocks);
            i=i+1;crd=crd+1;
            outstruct.objects.comp(i).NameAttribute='ADDITIONAL';
            outstruct.objects.comp(i).CaptionAttribute='GROUND POTENTIAL RISE';
            outstruct.objects.comp(i).CapanglAttribute=0;
            outstruct.objects.comp(i).CapPosXAttribute=-35;
            outstruct.objects.comp(i).CapPosYAttribute=35;
            outstruct.objects.comp(i).comp_content.PosXAttribute=TopLeftX+crd*2*bspac+(bspac+10);
            outstruct.objects.comp(i).comp_content.PosYAttribute=TopLeftY+2*bspac;
            outstruct.objects.comp(i).comp_content.IconAttribute='default';
            outstruct.objects.comp(i).comp_content.data.NameAttribute='Kind';
            outstruct.objects.comp(i).comp_content.data.UnitAttribute  ='';
            outstruct.objects.comp(i).comp_content.data.ValueAttribute  = 2; %2 = MODELS
            outstruct.objects.comp(i).comp_content.datastring = MODstr;


            % adds a sample text - TEXTS MUST COME AFTER THE COMPONENTS SECTION. You
            % have been warned.

            % i=1;
            % outstruct.objects.text(i).text_content.PosXAttribute=TopLeftX;
            % outstruct.objects.text(i).text_content.PosYAttribute=TopLeftY;
            % outstruct.objects.text(i).text_content.FontNameAttribute='Microsoft Sans Serif';
            % outstruct.objects.text(i).text_content.FontSizeAttribute=12;
            % outstruct.objects.text(i).text_content.FontStyleAttribute=2;
            % outstruct.objects.text(i).text_content.ColorAttribute=0;
            % outstruct.objects.text(i).text_content.OrientationAttribute=0;
            % outstruct.objects.text(i).text_content.BckColAttribute=16777215;
            % outstruct.objects.text(i).text_content.FrmColAttribute=16777215;
            % % outstruct.objects.text(i).text_content.Text=['Example label. Enforce a carriage return character to ↵ break line, or maybe a char(10)' char(10) 'like this.'];
            % outstruct.objects.text(i).text_content.Text=['(TopLeftX,TopLeftY)'];
            % i=i+1;
            % outstruct.objects.text(i).text_content.PosXAttribute=offsetX;
            % outstruct.objects.text(i).text_content.PosYAttribute=offsetY;
            % outstruct.objects.text(i).text_content.FontNameAttribute='Microsoft Sans Serif';
            % outstruct.objects.text(i).text_content.FontSizeAttribute=12;
            % outstruct.objects.text(i).text_content.FontStyleAttribute=2;
            % outstruct.objects.text(i).text_content.ColorAttribute=0;
            % outstruct.objects.text(i).text_content.OrientationAttribute=0;
            % outstruct.objects.text(i).text_content.BckColAttribute=16777215;
            % outstruct.objects.text(i).text_content.FrmColAttribute=16777215;
            % % outstruct.objects.text(i).text_content.Text=['Example label. Enforce a carriage return character to ↵ break line, or maybe a char(10)' char(10) 'like this.'];
            % outstruct.objects.text(i).text_content.Text=['(offsetX,offsetY)'];

            % writes ATPDraw variables
            outstruct.variables.NumSimAttribute=1;
            outstruct.variables.IOPCVPAttribute=0;
            outstruct.variables.UseParserAttribute='false';
            datastr={}; %does not seem to work when loading from XML
            datastr{1,1}=sprintf('RT=%1.2f ',app.TowerresistanceEditField.Value);
            % datastr{end+1,1}='RC=1.0E-5 ';
            outstruct.variables.VarStr = strjoin(datastr,char(10));


            %MERGE ALL COMPONENT STRUCTS
            outstruct.objects.comp = [outstruct.objects.comp towRstruct.objects.comp towSWstruct.objects.comp ...
                pipRstruct.objects.comp pipGstruct.objects.comp];

            %output to XML and fix non-conforming tags
            fullfname=fullfile(path,fname);

            writestruct(outstruct, fullfname,'StructNodeName','project');
            func_fix_text_labels(fullfname, fullfname)
            func_fix_var_tags(fullfname, fullfname)

            if isfile(fullfname)
                msg=sprintf('File %s created sucessfully!',fname);
                f = uimsgbox(app, msg, 'It works!','success');
            end

        end
    end %this is the end of the private methods section

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.defineZChoices
            app.defineYChoices
            app.defineSimuChoices

            orig_state = warning;warning('off','all');
            currentFolder = pwd;
            app.WorkdirectoryEditField.Value=pwd;
            addpath(fullfile(currentFolder,'functions'));
            warning(orig_state);
            t=table;
            t.PhaseNum=strings(0);
            t.Horiz=strings(0);
            t.Vert=strings(0);
            t.IntRadius=strings(0);
            t.ExtRadius=strings(0);
            t.CondResist=strings(0);
            t.CondPerm=strings(0);
            t.InsulRadius=strings(0);
            t.InsulPerm=strings(0);
            t.InsulPermit=strings(0);
            app.UITable.Data=t;
            app.UITable.ColumnEditable = [true true true true true true true true true true];

            tt=table;
            tt.PhaseNum=strings(0);
            tt.SendZ=strings(0);
            tt.RecZ=strings(0);
            app.UITableTermImpedances.Data=tt;
            app.UITableTermImpedances.ColumnEditable = [false true true];
%             app.UITable.ColumnWidth = {115,60,100,'auto'};
            

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            figure(app.mainform);
            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                % f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            nrows = size(app.UITableLayers.Data,1);
            if nrows > 0
                LayeredData=cell2matButNotStupid(app,app.UITableLayers.Data);
                if any(isnan(LayeredData)).visible
                    msg = 'The joys of creative input are lovely, but the laws of this universe impose that soil resistivity data must be all numeric.';
                    f = uimsgbox(app, msg, 'You lose, fella.','error');
                    % f = msgbox(msg, 'You lose, fella.','error');
                    return
                end
            end

            
            if  ~app.MatchwithcharacteristicimpedanceCheckBox.Value
                nrows = size(app.UITableTermImpedances.Data,1);
                ImpedanceData=cell2matButNotStupid(app,app.UITableTermImpedances.Data);
                if any(any(isnan(ImpedanceData)))
                    msg = 'I don''t fell like writing an informative error message right now, as you don''t seem inspired enough to realize that data in terminal impedances are supposed to be all numeric.'
                    f = uimsgbox(app, msg, 'You lose, fella.','error');
                    return
                end
            end

            %ok now do stuff
            jobid=app.JobIDEditField.Value;

            [testdir,msg]=validateWorkdir(app);
            if ~testdir
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                % f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            workdir=string(app.WorkdirectoryEditField.Value);
            orig_state = warning;warning('off','all');
            answer='Yes';

            if isfolder(fullfile(workdir,jobid))
                allfiles=dir(fullfile(workdir,jobid,'*.*'));
                

                if ~isempty(allfiles)
                    answer = uiquestdlg(app,sprintf('Output files were already found inside the JobID folder. If you proceed with this action, all existing output files WILL BE DESTROYED! \n\n Are you REALLY SURE that you want to continue? '), ...
                    '\WARNING\WARNING\WARNING\WARNING\WARNING\', ...
                    ["Yes","No"],1,2);
                    %questdlg('Warning: files were found in the target directory and will be deleted. Confirm?', ...
                    %    '\WARNING\WARNING\WARNING\WARNING\WARNING\', ...
                    %    'Yes','No','Yes');
                    if strcmp(answer,'No')
                        return;
                    else
                        try
                            if isfolder(fullfile(workdir,jobid,'plots'));rmdir(fullfile(workdir,jobid,'plots'), 's'); end
                            if isfolder(fullfile(workdir,jobid,'tralin_files')); rmdir(fullfile(workdir,jobid,'tralin_files'), 's'); end
                            if isfolder(fullfile(workdir,jobid,'femm_files')); rmdir(fullfile(workdir,jobid,'femm_files'), 's'); end
                            if isfile(fullfile(workdir,jobid,[jobid '_LineParam.mat'])); delete(fullfile(workdir,jobid,[jobid '_LineParam.mat'])); end
                            if isfile(fullfile(workdir,jobid,[jobid '_NLTSimu.mat'])); delete(fullfile(workdir,jobid,[jobid '_NLTSimu.mat'])); end
                            if isfile(fullfile(workdir,jobid,['RunJob_' jobid '.m'])); delete(fullfile(workdir,jobid,['RunJob_' jobid '.m'])); end
                            if isfile(fullfile(workdir,jobid,'LineData_fun.m')); delete(fullfile(workdir,jobid,'LineData_fun.m')); end
                        end
                    end
                end

            end
            
            mkdir(fullfile(workdir,jobid));
            warning(orig_state);
            fname=fullfile(workdir,jobid,'LineData_fun.m');
            test1 = writeLineFun(app,fname);
            if ~test1
                msg = 'Unable to write the line data function!';
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                % f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=fullfile(workdir,jobid,['RunJob_' jobid '.m']);
            test2 = writeMainRunFun(app,fname);
            if ~test2
                msg = 'Unable to write the main function wrapper!';
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                % f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            [folder,name,ext]=fileparts(fname);
            if test1 && test2 && strcmp(answer,'Yes')
                msg = sprintf('Project files created sucessfully! \n\n You may now open the ''%s'' folder and invoke the ''%s.m'' script to calculate some line parameters like a boss!',jobid,name);
                f = uimsgbox(app, msg, 'Success!','success');
                % f = msgbox(msg, 'Success!','help');
                return;
            end


        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            newrow={9999,9999,9999,9999,9999,9999,9999,9999,9999,9999};
            app.UITable.Data=[app.UITable.Data;newrow];
            redefRowLabels(app);
            redefFormulas(app);
            redefAdditionalFormulas(app);
            RefreshPreviewButtonPushed(app, event);
            redefTargetOptions(app)


        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            delrow=str2double(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if isnan(delrow)
                    app.UITable.Data(end,:)=[];
                else
                    app.UITable.Data(delrow,:)=[];
                end
                redefRowLabels(app);
                redefFormulas(app);
                redefAdditionalFormulas(app);
                redefTargetOptions(app)
                RefreshPreviewButtonPushed(app, event);
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete.Value=double(selectedRow);
                end

            end
        end

        % Button pushed function: SaveinputsessionButton
        function SaveinputsessionButtonPushed(app, event)
            saveAllData(app);
        end

        % Button pushed function: LoadinputsessionButton
        function LoadinputsessionButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            % app.mainform.Visible = 'off';
            % app.mainform.Visible = 'on';
            figure(app.mainform);
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'ohlt_data');
                    writeAllAppValues(app,ohlt_data);
                    %                     setColFormats(app);
%                     redefFormulas(app);
                    RefreshPreviewButtonPushed(app, event);
                catch
                    f = uimsgbox(app, 'Unable to load project file!','You lose, fella.','error');
                    % f = msgbox('Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            try
                test=isscalar((eval(newData)));
                redefFormulas(app);

                RefreshPreviewButtonPushed(app, event);
                redefImpedancesTable(app)

                redefTargetOptions(app)
           catch
                f = uimsgbox(app, 'Invalid data or MATLAB operator.','You lose, fella.','error');
                return;
            end
            
        end

        % Button pushed function: LoadWorkdirButton
        function LoadWorkdirButtonPushed(app, event)
            path=uigetdir(pwd,'Choose work directory');
            figure(app.mainform);
            % app.mainform.Visible = 'off';
            % app.mainform.Visible = 'on';
            if path ~=0
                app.WorkdirectoryEditField.Value=fullfile(path);
            end
        end

        % Button pushed function: RefreshPreview
        function RefreshPreviewButtonPushed(app, event)

            try
                % Clear the UIAxes before plotting
                cla(app.UIAxes);

                % Convert table data to array and then to double
                plotData = table2array(app.UITable.Data(:, 2:3));
                plotData = str2double(plotData);

                % Extract phase numbers
                phaseNum = str2double(app.UITable.Data.PhaseNum);

                % Create scatter plot with different colors for each phase
                uniquePhases = unique(phaseNum);
                colors = lines(length(uniquePhases)); % Generate unique colors

                hold(app.UIAxes, 'on');
                for k = 1:length(uniquePhases)
                    phaseIdx = (phaseNum == uniquePhases(k));
                    scatter(app.UIAxes, plotData(phaseIdx, 1), plotData(phaseIdx, 2), [], colors(k, :), 'filled');

                    % Add text labels next to each conductor
                    for m = find(phaseIdx)'
                        label = sprintf('C%d, Ph %d', m, phaseNum(m));
                        text(app.UIAxes, plotData(m, 1), plotData(m, 2), label, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
                    end
                end

                % Adjust xlim and ylim with a buffer
                buff = 5;
                xMin = min(plotData(:, 1)) - buff;
                xMax = max(plotData(:, 1)) + buff;
                yMin = min(plotData(:, 2)) - buff;
                yMax = max(plotData(:, 2)) + buff;
                app.UIAxes.XLim = [xMin, xMax];
                app.UIAxes.YLim = [yMin, yMax];
                app.UIAxes.Box = "on";
                app.UIAxes.XGrid = "on";
                app.UIAxes.YGrid = "on";

                % Plot horizontal line at y=0 to show the soil interface
                yline(app.UIAxes, 0, 'k-', 'LineWidth', 1.5);

                % Overlay shading from the negative limit of ylim to y=0
                fill(app.UIAxes, [xMin, xMax, xMax, xMin], [yMin, yMin, 0, 0], 'k', 'FaceAlpha', 0.1, 'EdgeColor', 'none');

                hold(app.UIAxes, 'off');
            catch ME
                % Handle errors
                disp('Error in plotting data:');
                disp(ME.message);
            end

            % try
            %     plotdata=table2array(app.UITable.Data(:,2:3));
            %     plotdata=str2double(plotdata);
            %     scatter(app.UIAxes, plotdata(:,1),plotdata(:,2));
            %     %app.UIAxes.YLim = [0.9*min(plotdata(:,2)) 1.1*max(plotdata(:,2))];
            % end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            MAXLAYERS=1;
            nrows = size(app.UITableLayers.Data,1);
            if nrows < MAXLAYERS
                addNewLayer(app);
            else
            f = uimsgbox(app, 'Currently only models up to two layers are supported (1 top layer + 1 uniform layer).','You lose, fella.','error');
            % f = msgbox('Currently only models up to two layers are supported (1 top layer + 1 uniform layer).','You lose, fella.','error');
                return;
            end
            redefFormulas(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delrow=str2double(app.Layer2Delete.Value);
            if ~isempty(app.UITableLayers.Data)
                if isnan(delrow)
                    app.UITableLayers.Data(end,:)=[];
                else
                    app.UITableLayers.Data(delrow,:)=[];
                end
                redefLayerLabels(app);
                redefFormulas(app);
            end
        end

        % Cell selection callback: UITableLayers
        function UITableLayersCellSelection(app, event)
            if ~isempty(app.UITableLayers.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.Layer2Delete.Value=double(selectedRow);
                end
            end
        end

        % Value changed function: ImpedanceDropDown
        function ImpedanceDropDownValueChanged(app, event)
            value = app.ImpedanceDropDown.Value;
            redefAdditionalFormulas(app);
        end

        % Value changed function: AdmittanceDropDown
        function AdmittanceDropDownValueChanged(app, event)
            value = app.AdmittanceDropDown.Value;              
            redefAdditionalFormulas(app);
        end

        % Value changed function: MatchwithcharacteristicimpedanceCheckBox
        function MatchwithcharacteristicimpedanceCheckBoxValueChanged(app, event)
            value = app.MatchwithcharacteristicimpedanceCheckBox.Value;
            if value
                app.UITableTermImpedances.Enable = 'off';
            else
                app.UITableTermImpedances.Enable = 'on';
            end

        end

        % Button pushed function: LoadWrapperScriptButton
        function LoadWrapperScriptButtonPushed(app, event)
            [file,path] = uigetfile({'*.m'});
            figure(app.mainform);
            if file ~=0
                fname=fullfile(path,file);
                app.WrapperScriptEditField.Value=fname;
            end
        end

        % Button pushed function: LoadCYZButton
        function LoadCYZButtonPushed(app, event)
            [file,path] = uigetfile({'*.cyz'});
            figure(app.mainform);
            if file ~=0
                fname=fullfile(path,file);
                app.CYZfileEditField.Value=fname;
            end
        end

        % Button pushed function: LoadMATButton
        function LoadMATButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat'});
            figure(app.mainform);
            if file ~=0
                fname=fullfile(path,file);
                app.MATfileEditField.Value=fname;
            end
        end

        % Button pushed function: ExportcrosssectiontoATPDrawButton
        function ExportcrosssectiontoATPDrawButtonPushed(app, event)
            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                return;
            end

            path=uigetdir(pwd,'Choose output directory');
            figure(app.mainform);
            if path ~=0
                exportCrossSection2XML(app,path);
            end
            figure(app.mainform);
        end

        % Button pushed function: LoadFEMPathButton
        function LoadFEMPathButtonPushed(app, event)
            path=uigetdir(pwd,'Choose FEM solver directory');
            figure(app.mainform);
            % app.mainform.Visible = 'off';
            % app.mainform.Visible = 'on';
            if path ~=0
                app.FEMPathEditField.Value=fullfile(path);
            end
        end

        % Button pushed function: LoadSourceCSVButton
        function LoadSourceCSVButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv'});
            figure(app.mainform);
            if file ~=0
                fname=fullfile(path,file);
                app.SourceCSVEditField.Value=fname;
            end
        end

        % Button pushed function: LoadTargetCSVButton
        function LoadTargetCSVButtonPushed(app, event)
            [file,path] = uigetfile({'*.csv'});
            figure(app.mainform);
            if file ~=0
                fname=fullfile(path,file);
                app.TargetCSVEditField.Value=fname;
            end
        end

        % Button pushed function: BuildEMImodelButton
        function BuildEMImodelButtonPushed(app, event)

            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                return;
            end

            path=uigetdir(pwd,'Choose output directory');
            figure(app.mainform);
            if path ~=0
                buildEMISimu(app,path);
            end
            figure(app.mainform);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.mainform = uifigure('Visible', 'off');
            app.mainform.Name = 'LineCableLab';
            app.mainform.Icon = fullfile(pathToMLAPP, 'appicon.png');
            app.TabGroup = uitabgroup(app.mainform);
            app.CasedataTab = uitab(app.TabGroup);
            app.CasedataTab.Title = 'Case data';
            app.UIAxes = uiaxes(app.CasedataTab);
            title(app.UIAxes, 'Cross-section preview')
            xlabel(app.UIAxes, 'Horizontal [m]')
            ylabel(app.UIAxes, 'Vertical [m]')
            zlabel(app.UIAxes, 'Z')
            app.GeneralPanel = uipanel(app.CasedataTab);
            app.GeneralPanel.Title = 'General';
            app.WorkdirectoryEditFieldLabel = uilabel(app.GeneralPanel);
            app.WorkdirectoryEditFieldLabel.Text = 'Work directory';
            app.WorkdirectoryEditField = uitextarea(app.GeneralPanel);
            app.LoadWorkdirButton = uibutton(app.GeneralPanel, 'push');
            app.LoadWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkdirButtonPushed, true);
            app.LoadWorkdirButton.Text = '📂';
            app.JobIDLabel = uilabel(app.GeneralPanel);
            app.JobIDLabel.Text = 'JobID';
            app.JobIDEditField = uieditfield(app.GeneralPanel, 'text');
            app.ProjectdescriptionLabel = uilabel(app.GeneralPanel);
            app.ProjectdescriptionLabel.Text = 'Project description';
            app.ProjectdescriptionTextArea = uitextarea(app.GeneralPanel);
            app.RefreshPreview = uibutton(app.CasedataTab, 'push');
            app.RefreshPreview.ButtonPushedFcn = createCallbackFcn(app, @RefreshPreviewButtonPushed, true);
            app.RefreshPreview.Tooltip = {'Refresh preview'};
            app.RefreshPreview.Text = '🗘';
            app.LinecrosssectionconductordataPanel = uipanel(app.CasedataTab);
            app.LinecrosssectionconductordataPanel.Title = 'Line cross-section & conductor data';
            app.AddrowButton = uibutton(app.LinecrosssectionconductordataPanel, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '➕ Add row';
            app.DeleterowButton = uibutton(app.LinecrosssectionconductordataPanel, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = '🗙 Delete row';
            app.CurrentrowLabel = uilabel(app.LinecrosssectionconductordataPanel);
            app.CurrentrowLabel.Text = 'Current row:';
            app.row2Delete = uieditfield(app.LinecrosssectionconductordataPanel, 'numeric');
            app.row2Delete.ValueDisplayFormat = '%.0f';
            app.row2Delete.Editable = 'off';
            app.LinelengthmEditFieldLabel = uilabel(app.LinecrosssectionconductordataPanel);
            app.LinelengthmEditFieldLabel.Text = 'Line length [m]';
            app.LinelengthmEditField = uieditfield(app.LinecrosssectionconductordataPanel, 'numeric');
            app.LinelengthmEditField.Value = 1000;
            app.ExportcrosssectiontoATPDrawButton = uibutton(app.LinecrosssectionconductordataPanel, 'push');
            app.ExportcrosssectiontoATPDrawButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcrosssectiontoATPDrawButtonPushed, true);
            app.ExportcrosssectiontoATPDrawButton.Text = '📐 Export cross-section to ATPDraw';
            app.UITable = uitable(app.LinecrosssectionconductordataPanel);
            app.UITable.ColumnName = {'PhaseNum'; 'Horiz [m]'; 'Vert [m]'; 'IntRadius [m]'; 'ExtRadius [m]'; 'CondResist [Ωm]'; 'CondPerm [pu]'; 'InsulRadius [m]'; 'InsulPerm [pu]'; 'InsulPermit [pu]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'Note: set PhaseNum=0 to enforce Kron elimination; '; 'use the same PhaseNum for bundled conductors.'};
            app.LinecomputationsTab = uitab(app.TabGroup);
            app.LinecomputationsTab.Title = 'Line computations';
            app.ComputelineparametersCheckBox = uicheckbox(app.LinecomputationsTab);
            app.ComputelineparametersCheckBox.Text = 'Compute line parameters';
            app.ComputelineparametersCheckBox.Value = true;
            app.FDsoilDropDownLabel = uilabel(app.LinecomputationsTab);
            app.FDsoilDropDownLabel.Text = 'FD soil ';
            app.FDsoilDropDown = uidropdown(app.LinecomputationsTab);
            app.FDsoilDropDown.Items = {'0 - Constant', '1 - Longmire & Smith', '2 - Portela', '3 - Alipio & Visacro', '4 - Datsios & Mikropoulos', '5 - Scott', '6 - Messier', '7 - Visacro & Portela', '8 - Visacro & Alipio', '9 - Cigré'};
            app.FDsoilDropDown.Editable = 'on';
            app.FDsoilDropDown.Value = '0 - Constant';
            app.GroundreturnmodelPanel = uipanel(app.LinecomputationsTab);
            app.GroundreturnmodelPanel.Title = 'Ground return model';
            app.ImpedanceDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.ImpedanceDropDownLabel.Text = 'Impedance';
            app.ImpedanceDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.ImpedanceDropDown.Items = {'Define cross-section...'};
            app.ImpedanceDropDown.Editable = 'on';
            app.ImpedanceDropDown.ValueChangedFcn = createCallbackFcn(app, @ImpedanceDropDownValueChanged, true);
            app.ImpedanceDropDown.Value = 'Define cross-section...';
            app.OtherZListBoxLabel = uilabel(app.GroundreturnmodelPanel);
            app.OtherZListBoxLabel.Text = 'Other Z';
            app.OtherZListBox = uilistbox(app.GroundreturnmodelPanel);
            app.OtherZListBox.Items = {'Hold CTRL and click to select formulas to compare...'};
            app.OtherZListBox.Multiselect = 'on';
            app.OtherZListBox.Value = {'Hold CTRL and click to select formulas to compare...'};
            app.AdmittanceDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.AdmittanceDropDownLabel.Text = 'Admittance';
            app.AdmittanceDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.AdmittanceDropDown.Items = {'Define cross-section...'};
            app.AdmittanceDropDown.Editable = 'on';
            app.AdmittanceDropDown.ValueChangedFcn = createCallbackFcn(app, @AdmittanceDropDownValueChanged, true);
            app.AdmittanceDropDown.Value = 'Define cross-section...';
            app.OtherYListBoxLabel = uilabel(app.GroundreturnmodelPanel);
            app.OtherYListBoxLabel.Text = 'Other Y';
            app.OtherYListBox = uilistbox(app.GroundreturnmodelPanel);
            app.OtherYListBox.Items = {'Hold CTRL and click to select formulas to compare...'};
            app.OtherYListBox.Multiselect = 'on';
            app.OtherYListBox.Value = {'Hold CTRL and click to select formulas to compare...'};
            app.ModaldecompositionDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.ModaldecompositionDropDownLabel.Text = 'Modal Ti';
            app.ModaldecompositionDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.ModaldecompositionDropDown.Items = {'1 - QR', '2 - QR ATP', '3 - simple_QR', '4 - simple_QR', '5 - NR', '6 - NR_back', '7 - SQP', '8 - SQP_back', '9 - LM (Levenberg-Marquardt)', '10 - LM_back', '11 - LM_fast', '12 - LM_alt', '13 - QR eigenvalue shuffle', '14 - QR intercheig'};
            app.ModaldecompositionDropDown.Editable = 'on';
            app.ModaldecompositionDropDown.Value = '9 - LM (Levenberg-Marquardt)';
            app.UppersoillayersLabel = uilabel(app.LinecomputationsTab);
            app.UppersoillayersLabel.Text = 'Upper soil layers:';
            app.AddButton = uibutton(app.LinecomputationsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '➕ Add';
            app.DeleteButton = uibutton(app.LinecomputationsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '🗙 Delete';
            app.CurrentrowLabel_2 = uilabel(app.LinecomputationsTab);
            app.CurrentrowLabel_2.Text = 'Current layer:';
            app.Layer2Delete = uieditfield(app.LinecomputationsTab, 'numeric');
            app.Layer2Delete.ValueDisplayFormat = '%.0f';
            app.Layer2Delete.Editable = 'off';
            app.UITableLayers = uitable(app.LinecomputationsTab);
            app.UITableLayers.ColumnName = {'Resistivity [Ωm]'; 'Permeability [pu]'; 'Permittivity [pu]'; 'Thickness [m]'};
            app.UITableLayers.RowName = {};
            app.UITableLayers.ColumnEditable = true;
            app.UITableLayers.CellSelectionCallback = createCallbackFcn(app, @UITableLayersCellSelection, true);
            app.UniformbottomsoillayerPanel = uipanel(app.LinecomputationsTab);
            app.UniformbottomsoillayerPanel.Title = 'Uniform (bottom) soil layer';
            app.GridLayout = uigridlayout(app.UniformbottomsoillayerPanel);
            app.ResistivitymEditFieldLabel = uilabel(app.GridLayout);
            app.ResistivitymEditFieldLabel.Text = 'Resistivity [Ωm]';
            app.PermeabilitypuEditFieldLabel = uilabel(app.GridLayout);
            app.PermeabilitypuEditFieldLabel.Text = 'Permeability [pu]';
            app.PermittivitypuEditFieldLabel = uilabel(app.GridLayout);
            app.PermittivitypuEditFieldLabel.Text = 'Permittivity [pu]';
            app.ResistivitymEditField = uieditfield(app.GridLayout, 'numeric');
            app.ResistivitymEditField.Value = 100;
            app.PermeabilitypuEditField = uieditfield(app.GridLayout, 'numeric');
            app.PermeabilitypuEditField.Value = 1;
            app.PermittivitypuEditField = uieditfield(app.GridLayout, 'numeric');
            app.PermittivitypuEditField.Value = 1;
            app.FrequencysweeplogspacedpointsPanel = uipanel(app.LinecomputationsTab);
            app.FrequencysweeplogspacedpointsPanel.Title = 'Frequency sweep (logspaced points)';
            app.Lowerdecade10LHzSpinnerLabel = uilabel(app.FrequencysweeplogspacedpointsPanel);
            app.Lowerdecade10LHzSpinnerLabel.Text = 'Lower decade [10^L Hz]';
            app.Lowerdecade10LHzSpinner = uispinner(app.FrequencysweeplogspacedpointsPanel);
            app.Lowerdecade10LHzSpinner.Limits = [-10 Inf];
            app.Lowerdecade10LHzSpinner.ValueDisplayFormat = '%d';
            app.Lowerdecade10LHzSpinner.Value = -1;
            app.Upperdecade10UHzSpinnerLabel = uilabel(app.FrequencysweeplogspacedpointsPanel);
            app.Upperdecade10UHzSpinnerLabel.Text = 'Upper decade [10^U Hz]';
            app.Upperdecade10UHzSpinner = uispinner(app.FrequencysweeplogspacedpointsPanel);
            app.Upperdecade10UHzSpinner.Limits = [-10 Inf];
            app.Upperdecade10UHzSpinner.ValueDisplayFormat = '%d';
            app.Upperdecade10UHzSpinner.Value = 6;
            app.PointsperdecadeSpinnerLabel = uilabel(app.FrequencysweeplogspacedpointsPanel);
            app.PointsperdecadeSpinnerLabel.Text = 'Points per decade';
            app.PointsperdecadeSpinner = uispinner(app.FrequencysweeplogspacedpointsPanel);
            app.PointsperdecadeSpinner.Limits = [2 Inf];
            app.PointsperdecadeSpinner.ValueDisplayFormat = '%d';
            app.PointsperdecadeSpinner.Value = 10;
            app.PlotsavePanel = uipanel(app.LinecomputationsTab);
            app.PlotsavePanel.Title = 'Plot & save';
            app.RequestpulparametersplotsCheckBox = uicheckbox(app.PlotsavePanel);
            app.RequestpulparametersplotsCheckBox.Text = 'Request pul parameters plots';
            app.RequestpulparametersplotsCheckBox.Value = true;
            app.RequestmodalparametersplotsCheckBox = uicheckbox(app.PlotsavePanel);
            app.RequestmodalparametersplotsCheckBox.Text = 'Request modal parameters plots';
            app.RequestmodalparametersplotsCheckBox.Value = true;
            app.SavedatatoMATfilesCheckBox = uicheckbox(app.PlotsavePanel);
            app.SavedatatoMATfilesCheckBox.Text = 'Save data to MAT-files';
            app.SavedatatoMATfilesCheckBox.Value = true;
            app.SaveallplotsCheckBox = uicheckbox(app.PlotsavePanel);
            app.SaveallplotsCheckBox.Text = 'Save all plots';
            app.SaveallplotsCheckBox.Value = true;
            app.ExportlinemodelPanel = uipanel(app.LinecomputationsTab);
            app.ExportlinemodelPanel.Title = 'Export line model';
            app.EMTPcompliantLineCable_DataMATfileCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.EMTPcompliantLineCable_DataMATfileCheckBox.Text = ' EMTP-compliant LineCable_Data  MAT-file';
            app.ATPDrawcompliantXMLfileCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.ATPDrawcompliantXMLfileCheckBox.Text = 'ATPDraw-compliant XML file';
            app.ATPTYPE94fitULMmodelDeContietalCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.ATPTYPE94fitULMmodelDeContietalCheckBox.Text = 'ATP TYPE-94 fitULM model (De Conti et al.)';
            app.ATPcompliantPCHJMartimodelCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.ATPcompliantPCHJMartimodelCheckBox.Enable = 'off';
            app.ATPcompliantPCHJMartimodelCheckBox.Text = 'ATP-compliant PCH JMarti model';
            app.FittingoptionsPanel = uipanel(app.LinecomputationsTab);
            app.FittingoptionsPanel.Title = 'Fitting options';
            app.MinPolesEditFieldLabel = uilabel(app.FittingoptionsPanel);
            app.MinPolesEditFieldLabel.Text = 'MinPoles';
            app.MinPolesEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.MinPolesEditField.Limits = [2 Inf];
            app.MinPolesEditField.RoundFractionalValues = 'on';
            app.MinPolesEditField.ValueDisplayFormat = '%d';
            app.MinPolesEditField.Value = 2;
            app.MaxPolesEditFieldLabel = uilabel(app.FittingoptionsPanel);
            app.MaxPolesEditFieldLabel.Text = 'MaxPoles';
            app.MaxPolesEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.MaxPolesEditField.Limits = [0 Inf];
            app.MaxPolesEditField.RoundFractionalValues = 'on';
            app.MaxPolesEditField.ValueDisplayFormat = '%d';
            app.MaxPolesEditField.Value = 20;
            app.VFTOLLabel = uilabel(app.FittingoptionsPanel);
            app.VFTOLLabel.Text = 'VFTOL [pu]';
            app.VFTOLpuEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.VFTOLpuEditField.Limits = [0 1];
            app.VFTOLpuEditField.Value = 0.01;
            app.BFTOLdBEditFieldLabel = uilabel(app.FittingoptionsPanel);
            app.BFTOLdBEditFieldLabel.Enable = 'off';
            app.BFTOLdBEditFieldLabel.Text = 'BFTOL [dB]';
            app.BFTOLdBEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.BFTOLdBEditField.Limits = [0 Inf];
            app.BFTOLdBEditField.RoundFractionalValues = 'on';
            app.BFTOLdBEditField.ValueDisplayFormat = '%d';
            app.BFTOLdBEditField.Enable = 'off';
            app.BFTOLdBEditField.Value = 3;
            app.SimToolTab = uitab(app.TabGroup);
            app.SimToolTab.Title = 'SimTool';
            app.EnableNLTengineCheckBox = uicheckbox(app.SimToolTab);
            app.EnableNLTengineCheckBox.Text = 'Enable NLT engine';
            app.EMIsimulationPanel = uipanel(app.SimToolTab);
            app.EMIsimulationPanel.Title = 'EMI simulation';
            app.SourceCSVEditFieldLabel = uilabel(app.EMIsimulationPanel);
            app.SourceCSVEditFieldLabel.Text = 'Source CSV';
            app.SourceCSVEditField = uieditfield(app.EMIsimulationPanel, 'text');
            app.LoadSourceCSVButton = uibutton(app.EMIsimulationPanel, 'push');
            app.LoadSourceCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSourceCSVButtonPushed, true);
            app.LoadSourceCSVButton.Text = '📂';
            app.TargetCSVEditFieldLabel = uilabel(app.EMIsimulationPanel);
            app.TargetCSVEditFieldLabel.Text = 'Target CSV';
            app.TargetCSVEditField = uieditfield(app.EMIsimulationPanel, 'text');
            app.LoadTargetCSVButton = uibutton(app.EMIsimulationPanel, 'push');
            app.LoadTargetCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTargetCSVButtonPushed, true);
            app.LoadTargetCSVButton.Text = '📂';
            app.SubdivisionmEditFieldLabel = uilabel(app.EMIsimulationPanel);
            app.SubdivisionmEditFieldLabel.Text = 'Subdivision [m]';
            app.SubdivisionmEditField = uieditfield(app.EMIsimulationPanel, 'numeric');
            app.SubdivisionmEditField.Limits = [0 Inf];
            app.SubdivisionmEditField.Value = 100;
            app.FrequencyHzEditFieldLabel = uilabel(app.EMIsimulationPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.EMIsimulationPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0 Inf];
            app.FrequencyHzEditField.Value = 50;
            app.TargetphaseDropDownLabel = uilabel(app.EMIsimulationPanel);
            app.TargetphaseDropDownLabel.Text = 'Target phase';
            app.TargetphaseDropDown = uidropdown(app.EMIsimulationPanel);
            app.TargetphaseDropDown.Items = {};
            app.TargetphaseDropDown.Editable = 'on';
            app.TargetphaseDropDown.Value = {};
            app.CoatingresistivitymEditFieldLabel = uilabel(app.EMIsimulationPanel);
            app.CoatingresistivitymEditFieldLabel.Text = 'Coating resistivity [Ωm]';
            app.CoatingresistivitymEditField = uieditfield(app.EMIsimulationPanel, 'numeric');
            app.CoatingresistivitymEditField.Limits = [0 Inf];
            app.CoatingresistivitymEditField.Value = 100000000;
            app.ShieldwiresListBoxLabel = uilabel(app.EMIsimulationPanel);
            app.ShieldwiresListBoxLabel.Text = 'Shield wires';
            app.ShieldwiresListBox = uilistbox(app.EMIsimulationPanel);
            app.ShieldwiresListBox.Items = {};
            app.ShieldwiresListBox.Multiselect = 'on';
            app.ShieldwiresListBox.Value = {};
            app.TowerresistanceLabel = uilabel(app.EMIsimulationPanel);
            app.TowerresistanceLabel.Text = 'Tower resistance [Ω]';
            app.TowerresistanceEditField = uieditfield(app.EMIsimulationPanel, 'numeric');
            app.TowerresistanceEditField.Limits = [0 Inf];
            app.TowerresistanceEditField.Value = 15;
            app.BuildEMImodelButton = uibutton(app.EMIsimulationPanel, 'push');
            app.BuildEMImodelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildEMImodelButtonPushed, true);
            app.BuildEMImodelButton.Text = '🏗️ Build EMI model';
            app.SimulationtypeDropDownLabel = uilabel(app.SimToolTab);
            app.SimulationtypeDropDownLabel.Text = 'Simulation type';
            app.SimulationtypeDropDown = uidropdown(app.SimToolTab);
            app.SimulationtypeDropDown.Items = {'Choose a flag...'};
            app.SimulationtypeDropDown.Editable = 'on';
            app.SimulationtypeDropDown.Value = 'Choose a flag...';
            app.SamplingrateHzEditFieldLabel = uilabel(app.SimToolTab);
            app.SamplingrateHzEditFieldLabel.Text = 'Sampling rate [Hz]';
            app.SamplingrateHzEditField = uieditfield(app.SimToolTab, 'numeric');
            app.SamplingrateHzEditField.Limits = [0 Inf];
            app.SamplingrateHzEditField.Value = 10000000;
            app.SimulationtimesLabel = uilabel(app.SimToolTab);
            app.SimulationtimesLabel.Text = 'Simulation time [s]';
            app.SimulationtimesEditField = uieditfield(app.SimToolTab, 'numeric');
            app.SimulationtimesEditField.Limits = [0 Inf];
            app.SimulationtimesEditField.Value = 0.1;
            app.StarttimesEditFieldLabel = uilabel(app.SimToolTab);
            app.StarttimesEditFieldLabel.Text = 'Start time [s]';
            app.StarttimesEditField = uieditfield(app.SimToolTab, 'numeric');
            app.StarttimesEditField.Limits = [0 Inf];
            app.SourceamplitudepuEditFieldLabel = uilabel(app.SimToolTab);
            app.SourceamplitudepuEditFieldLabel.Text = 'Source amplitude [pu]';
            app.SourceamplitudepuEditField = uieditfield(app.SimToolTab, 'numeric');
            app.SourceamplitudepuEditField.Limits = [0 Inf];
            app.SourceamplitudepuEditField.Value = 1;
            app.RequestNLTplotsCheckBox = uicheckbox(app.SimToolTab);
            app.RequestNLTplotsCheckBox.Text = 'Request NLT plots';
            app.RequestNLTplotsCheckBox.Value = true;
            app.TerminationimpedancesLabel = uilabel(app.SimToolTab);
            app.TerminationimpedancesLabel.Text = 'Termination impedances';
            app.UITableTermImpedances = uitable(app.SimToolTab);
            app.UITableTermImpedances.ColumnName = {'PhaseNum'; 'Sending terminal [Ω]'; 'Receiving terminal [Ω]'};
            app.UITableTermImpedances.RowName = {};
            app.UITableTermImpedances.ColumnEditable = [false true true];
            app.MatchwithcharacteristicimpedanceCheckBox = uicheckbox(app.SimToolTab);
            app.MatchwithcharacteristicimpedanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MatchwithcharacteristicimpedanceCheckBoxValueChanged, true);
            app.MatchwithcharacteristicimpedanceCheckBox.Text = 'Match with phase-based characteristic impedance (will override specified values)';
            app.ExperimentsTab = uitab(app.TabGroup);
            app.ExperimentsTab.Title = '⚗️ Experiments';
            app.UseexternalprocesstocomputelineparametersCheckBox = uicheckbox(app.ExperimentsTab);
            app.UseexternalprocesstocomputelineparametersCheckBox.Text = 'Use external process to compute line parameters';
            app.WrapperScriptEditFieldLabel = uilabel(app.ExperimentsTab);
            app.WrapperScriptEditFieldLabel.Text = 'Wrapper script';
            app.WrapperScriptEditField = uieditfield(app.ExperimentsTab, 'text');
            app.LoadWrapperScriptButton = uibutton(app.ExperimentsTab, 'push');
            app.LoadWrapperScriptButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWrapperScriptButtonPushed, true);
            app.LoadWrapperScriptButton.Text = '📂';
            app.LoadpulZYfromLineCable_DataCheckBox = uicheckbox(app.ExperimentsTab);
            app.LoadpulZYfromLineCable_DataCheckBox.Text = 'Load pul ZY from LineCable_Data';
            app.CYZfiletEditFieldLabel = uilabel(app.ExperimentsTab);
            app.CYZfiletEditFieldLabel.Text = 'CYZ-file';
            app.CYZfileEditField = uieditfield(app.ExperimentsTab, 'text');
            app.LoadCYZButton = uibutton(app.ExperimentsTab, 'push');
            app.LoadCYZButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCYZButtonPushed, true);
            app.LoadCYZButton.Text = '📂';
            app.LoadpulZYfromuserdefinedMATfileCheckBox = uicheckbox(app.ExperimentsTab);
            app.LoadpulZYfromuserdefinedMATfileCheckBox.Text = 'Load pul ZY from user-defined MAT-file';
            app.MATfileEditFieldLabel = uilabel(app.ExperimentsTab);
            app.MATfileEditFieldLabel.Text = 'MAT-file';
            app.MATfileEditField = uieditfield(app.ExperimentsTab, 'text');
            app.LoadMATButton = uibutton(app.ExperimentsTab, 'push');
            app.LoadMATButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMATButtonPushed, true);
            app.LoadMATButton.Text = '📂';
            app.EnableFEMengineCheckBox = uicheckbox(app.ExperimentsTab);
            app.EnableFEMengineCheckBox.Text = 'Enable FEM engine';
            app.FEMPathEditFieldLabel = uilabel(app.ExperimentsTab);
            app.FEMPathEditFieldLabel.Text = 'Solver folder';
            app.FEMPathEditField = uieditfield(app.ExperimentsTab, 'text');
            app.LoadFEMPathButton = uibutton(app.ExperimentsTab, 'push');
            app.LoadFEMPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFEMPathButtonPushed, true);
            app.LoadFEMPathButton.Text = '📂';
            app.ConductordatabaseButton = uibutton(app.mainform, 'push');
            app.ConductordatabaseButton.Enable = 'off';
            app.ConductordatabaseButton.Visible = 'off';
            app.ConductordatabaseButton.Text = '🗃️ Conductor database';
            app.SaveinputsessionButton = uibutton(app.mainform, 'push');
            app.SaveinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinputsessionButtonPushed, true);
            app.SaveinputsessionButton.Text = '🖪 Save input session';
            app.LoadinputsessionButton = uibutton(app.mainform, 'push');
            app.LoadinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadinputsessionButtonPushed, true);
            app.LoadinputsessionButton.Text = '📁 Load input session';
            app.StartButton = uibutton(app.mainform, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '▶️ Start!';
            app.ExitButton = uibutton(app.mainform, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '🚪 Exit';
            % Show the figure after all components are created
            app.mainform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linecablelab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainform)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainform)
        end
    end
end