classdef ohlt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainform                        matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        LoadinputsessionButton          matlab.ui.control.Button
        SaveinputsessionButton          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CasedataTab                     matlab.ui.container.Tab
        RefreshPreview                  matlab.ui.control.Button
        GeneralPanel                    matlab.ui.container.Panel
        ProjectdescriptionTextArea      matlab.ui.control.TextArea
        ProjectdescriptionLabel         matlab.ui.control.Label
        JobIDEditField                  matlab.ui.control.EditField
        JobIDLabel                      matlab.ui.control.Label
        WorkdirectoryEditField          matlab.ui.control.EditField
        WorkdirectoryEditFieldLabel     matlab.ui.control.Label
        LoadWorkdirButton               matlab.ui.control.Button
        OHLcrosssectionconductordataPanel  matlab.ui.container.Panel
        row2Delete                      matlab.ui.control.NumericEditField
        CurrentrowLabel                 matlab.ui.control.Label
        NotesetPhaseNum0toenforceKroneliminationLabel  matlab.ui.control.Label
        ConductordatabaseButton         matlab.ui.control.Button
        DeleterowButton                 matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        ComputationsettingsTab          matlab.ui.container.Tab
        OHLTlengthmEditField            matlab.ui.control.NumericEditField
        OHLTlengthmEditFieldLabel       matlab.ui.control.Label
        MiscPanel                       matlab.ui.container.Panel
        ExtFileEditField                matlab.ui.control.EditField
        ExtFileEditFieldLabel           matlab.ui.control.Label
        LoadExtFileButton               matlab.ui.control.Button
        ExportlinemodeltoLabel          matlab.ui.control.Label
        ATPcompliantPCHJMartimodelCheckBox  matlab.ui.control.CheckBox
        EMTPcompliantLineCable_DataMATfileCheckBox  matlab.ui.control.CheckBox
        PythonPathEditField             matlab.ui.control.EditField
        PythonPathEditFieldLabel        matlab.ui.control.Label
        LoadPythonPathButton            matlab.ui.control.Button
        PropagationcharacteristicsPanel  matlab.ui.container.Panel
        SavadatatoMATfilesCheckBox      matlab.ui.control.CheckBox
        RequestpropagconstvelplotsCheckBox  matlab.ui.control.CheckBox
        RequestZcplotsCheckBox          matlab.ui.control.CheckBox
        LayeredsoilLabel                matlab.ui.control.Label
        CurrentrowLabel_2               matlab.ui.control.Label
        row2Delete_2                    matlab.ui.control.NumericEditField
        DeleteButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        FrequencysweepPanel             matlab.ui.container.Panel
        UselogspacedpointsCheckBox      matlab.ui.control.CheckBox
        PointsDecadeSpinner             matlab.ui.control.Spinner
        PointsDecadeSpinnerLabel        matlab.ui.control.Label
        DecadesSpinner                  matlab.ui.control.Spinner
        DecadesSpinnerLabel             matlab.ui.control.Label
        ModaltransformPanel             matlab.ui.container.Panel
        RequesttransformmatricesplotsCheckBox  matlab.ui.control.CheckBox
        ModaldecompositionflagDropDown  matlab.ui.control.DropDown
        ModaldecompositionflagDropDownLabel  matlab.ui.control.Label
        ModaltransformmatrixDropDown    matlab.ui.control.DropDown
        ModaltransformmatrixDropDownLabel  matlab.ui.control.Label
        GroundreturnmodelPanel          matlab.ui.container.Panel
        RequestpulparametersplotsCheckBox  matlab.ui.control.CheckBox
        AdmittanceformulaDropDown       matlab.ui.control.DropDown
        AdmittanceformulaDropDownLabel  matlab.ui.control.Label
        ImpedanceformulaDropDown        matlab.ui.control.DropDown
        ImpedanceformulaDropDownLabel   matlab.ui.control.Label
        UniformdeepsoillayerPanel       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        PermittivitypuEditField         matlab.ui.control.NumericEditField
        PermittivitypuEditFieldLabel    matlab.ui.control.Label
        PermeabilitypuEditField         matlab.ui.control.NumericEditField
        PermeabilitypuEditFieldLabel    matlab.ui.control.Label
        ResistivitymEditField           matlab.ui.control.NumericEditField
        ResistivitymEditFieldLabel      matlab.ui.control.Label
        FDsoilmodelDropDown             matlab.ui.control.DropDown
        FDsoilmodelDropDownLabel        matlab.ui.control.Label
    end


    methods (Access = private)

        function [isgood, msg] = validateFilename(app)
            fname=app.TemplateATPfileatpEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'file')
                msg='Invalid file name!';
                isgood=false;
                return;
            end
        end


        function [results, msg] = loadFromFile(app,fname)
            results=[];
            msg=[];
            try
                fid=fopen(fname);
                data=textscan(fid, '%s', 'delimiter', '\n');
                fclose(fid);
                data=data{1,1};
            catch
                msg='File contains invalid data!';
                try;fclose(fid);catch;end;
                return;
            end
            idx = find(contains(data,'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>'));
            tmp_str=string(data(idx(1)+1));
            tmp_str=split(tmp_str,' ');
            tmp_str(tmp_str=="")=[];
            results.dT=tmp_str(1);
            results.Tmax=tmp_str(2);
            results.freq=tmp_str(3);

            idx = find(contains(data,'$INCLUDE'));
            idx = [idx; find(contains(data,'$INSERT'))];
            for k=1:length(idx)
                tmp_str=string(data(idx(k)));
                tmp_str=split(tmp_str,',');
                inc_names{k,1}=char(strtrim(tmp_str(2)));
            end
            results.include=unique(inc_names);

            idx = find(contains(data,'$PARAMETER'));
            c=0;
            for k = idx(1)+1:1:idx(end)-1
                tmp_str=string(data(k));
                tmp_str=split(tmp_str,'=');
                if contains(tmp_str(2),'$$')
                    % found a primary variable
                    c=c+1;
                    varnames{c,1}=strtrim(tmp_str(1));
                    tmp_str2=split(tmp_str(2),'$$');
                    varvalues{c,1}=strtrim(tmp_str2(1));
                    vartype{c,1}='numeric';
                elseif contains(tmp_str(2),'''')
                    % found a string variable
                    c=c+1;
                    varnames{c,1}=strtrim(tmp_str(1));
                    varvalues{c,1}=strtrim(tmp_str(2));
                    vartype{c,1}='string';
                end
            end
            results.params=[varnames vartype varvalues varvalues];
        end


        function out = redefRowLabels(app)
            nrows = size(app.UITable.Data,1);
            app.UITable.RowName=string(1:nrows);
        end

        function results = saveAllData(app)

            results=false;
            %[testdir,msg]=validateWorkDir(app);
            %if ~testdir
            %    f = msgbox(msg, 'You lose, fella.','error');
            %    return;
            %end

            [testfile,msg]=validateProjID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            %ok now do stuff
            projID=app.JobIDEditField.Value;
            [~,wdir] = uiputfile('*.mat','Save input',[projID '.mat']);

            %             wdir=app.WorkingdirectoryEditField.Value;
            if wdir ~= 0
                projdir=fullfile(wdir);
                %             orig_state = warning;warning('off','all');
                %             mkdir(projdir); warning(orig_state);
                %             mkdir(projdir);
                fname=[projID '.mat'];
                atpcasegen_data = readAllAppValues(app);
                save(fullfile(projdir,fname),'atpcasegen_data');
                %             warning(orig_state);
                results=true;
            end
        end

        function results = writeAllAppValues(app,atpcasegen_data)

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')


                    if isprop(app.(thisprop),'Value')
                        app.(thisprop).Value=atpcasegen_data.(thisprop).Value;
                    end

                    app.(thisprop).Enable=atpcasegen_data.(thisprop).Enable;
                    app.(thisprop).Visible=atpcasegen_data.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        app.(thisprop).Editable=atpcasegen_data.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        app.(thisprop).List=atpcasegen_data.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Data')
                        app.(thisprop).Data=atpcasegen_data.(thisprop).Data;
                    end

                end

            end
        end

        function appdata = readAllAppValues(app)
            appdata=struct;

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')

                    appdata.(thisprop)=struct;

                    if isprop(app.(thisprop),'Value')
                        appdata.(thisprop).Value=app.(thisprop).Value;
                    end

                    appdata.(thisprop).Enable=app.(thisprop).Enable;
                    appdata.(thisprop).Visible=app.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        appdata.(thisprop).Editable=app.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        appdata.(thisprop).List=app.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Data')
                        appdata.(thisprop).Data=app.(thisprop).Data;
                    end

                end

            end
        end

        function [isgood, msg] = validateProjID(app)
            projID=app.JobIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(projID)
                isgood=false;
                msg='You must specify a project ID!';
                return;
            else
                bad = ismember(BadChar, projID);
                if any(bad)
                    msg = ['Project ID contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(projID < 32)
                    msg = ['Project ID  contains non printable characters, ASCII:', sprintf(' %d', projID(projID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(projID) && (projID(end) == ' ' || projID(end) == '.')
                    msg = 'Project ID must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(projID);
                    if any(strcmpi(name, BadName))
                        msg = ['Project ID not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end

        function [vars, vartable] = buildCaseData(app)
            numvars=size(app.UITable.Data,1);
            str_allcomb='allcomb(';

            for k=1:numvars
                vars(k).param=app.UITable.Data.PARAM(k);
                vars(k).type=app.UITable.Data.Type(k);
                tmp_eval=eval(app.UITable.Data.NewValues(k));
                if iscell(tmp_eval)
                    vars(k).data=tmp_eval;
                elseif isnumeric(tmp_eval)
                    vars(k).data=num2cell(tmp_eval);
                elseif ischar(tmp_eval)
                    vars(k).data={tmp_eval};
                end
                str_allcomb=[str_allcomb sprintf('vars(%d).data,',k)];
            end
            str_allcomb=[str_allcomb(1:end-1) ')'];
            vartable=eval(str_allcomb);

        end


        function [outfile] = writeATPCaseFiles(app,infile,jobid,vars,allcases_data)
            outfile=[];
            workdir=app.WorkdirectoryEditField.Value;
            try
                fid=fopen(infile);
                data=textscan(fid, '%s', 'Delimiter', '\n', 'CollectOutput', true, 'Whitespace', '');
                fclose(fid);
                data=data{1,1};

                numvars=size(vars,2);
                numcases=size(allcases_data,1);
                app.ProgrammessagesTextArea.Value{end+1} = ['****** Building ATP files...'];
                for j=1:numcases
                    app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Now working case #%d.',j)];
                    for k=1:numvars
                        idx = find(contains(data,[char(vars(k).param) ' =']));
                        if vars(k).type == 'numeric'
                            data{idx} = [char(vars(k).param) ' = ' sprintf('%1.2f',allcases_data{j,k}) ' $$'];
                        elseif vars(k).type == 'string'
                            data{idx} = [char(vars(k).param) ' = ' sprintf('''%s''',allcases_data{j,k})];
                        end
                    end
                    %         idx = find(startsWith(data,'C'));
                    %         data(idx,:)=[]; %remove comments
                    %         idx = find(startsWith(data,'--'));
                    %         data(idx,:)=[]; %remove comments from MODELS

                    %         outfile='/home/amauri/Documents/_ProjCEPEL/BasesKleber/AT/ArquivosATP/teste.atp';
                    head{1,1}='C ------------------------------------------------------------------------------';
                    head{end+1,1}='C Case file generated using AutoATPCaseGen';
                    head{end+1,1}='C AutoATPCaseGen developed by A. G. Martins-Britto at UnB - Brazil - 2022';
                    head{end+1,1}='C github.com/amaurigmartins';
                    head{end+1,1}='C ';
                    head{end+1,1}=sprintf('C JobID: %s',app.JobIDEditField.Value);
                    if ~isempty(app.ProjectdescriptionTextArea.Value{1})
                        head{end+1,1}=sprintf('C Project description:');
                        head{end+1,1}=sprintf('C -------------------');
                        for k=1:size(app.ProjectdescriptionTextArea.Value,1)
                            if ~isempty(app.ProjectdescriptionTextArea.Value{k})
                                head{end+1,1}=sprintf('C %s',app.ProjectdescriptionTextArea.Value{k});
                            end
                        end
                    end
                    head{end+1,1}='C ------------------------------------------------------------------------------';

                    idx = find(contains(data,'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>'));
                    dT=app.deltaTsEditField.Value;
                    Tmax=app.TmaxsEditField.Value;
                    Copt=app.FreqHzEditField.Value;
                    tmp_str=blanks(80);

                    tmp_str(1:8)= sprintf('% 8.8G',dT);
                    tmp_str(9:16)=sprintf('% 8.8G',Tmax);
                    tmp_str(17:24)=sprintf('% 8.8G',Copt);
                    tmp_str(25:32)=sprintf('% 8.8G',Copt);
                    data{idx+1}=tmp_str;
                   
                    data=[head; data];
                    out=strjoin(data, '\n') ;%para escrever o arquivo
                    out=strrep(out,'\','\\');
                    out=strrep(out,'%','%%');
                    %         out = regexprep(out, {'\r', '\n\n+'}, {'', '\n'});
                    %         out=strcat(out,'\n');
                    outfile = cellfun(@num2str,allcases_data(j,:),'UniformOutput',false);
                    outfile = strjoin(outfile,'_');
                    outfile = sprintf('c%d_%s.atp',j,outfile);
                    filelist{j}=outfile;


                    fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                    fprintf(fid, out);
                    fclose(fid);
                end
                app.ProgrammessagesTextArea.Value{end+1} = ['Building cases index...'];
                C = [filelist' allcases_data];
                T = cell2table(C, "VariableNames",["Filename" [vars.param]]);
                indexfname = fullfile(workdir,jobid,'CasesIndex.xlsx');
                writetable(T,indexfname);
                app.ProgrammessagesTextArea.Value{end+1} = ['All done!'];


            catch
                msg='Unable to write output file!';
                f = msgbox(msg,'You lose, fella.','error');
                app.ProgrammessagesTextArea.Value{end+1} = ['Something went wrong (and no, I am not very good with making descriptive messages).'];
                try;fclose(fid);catch;end;
                return;
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            orig_state = warning;warning('off','all');
            currentFolder = pwd;
            app.WorkdirectoryEditField.Value=pwd;
            addpath(fullfile(currentFolder,'functions'));
            warning(orig_state);
            t=table;
            t.PhaseNum=strings(0);
            t.Horiz=strings(0);
            t.Vert=strings(0);
            t.IntRadius=strings(0);
            t.ExtRadius=strings(0);
            t.CondResist=strings(0);
            t.CondPerm=strings(0);
            t.InsulRadius=strings(0);
            t.InsulPerm=strings(0);
            t.InsulPermit=strings(0);
            app.UITable.Data=t;
            app.UITable.ColumnEditable = [true true true true true true true true true true];
%             app.UITable.ColumnWidth = {115,60,100,'auto'};

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function LoadFileButtonPushed(app, event)

            [file,path] = uigetfile({'*.atp'});
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.TemplateATPfileatpEditField.Value=fname;
                Load2TableButtonPushed(app, event);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            nonechecked=true;
            jobid=app.JobIDEditField.Value;
            infile=app.TemplateATPfileatpEditField.Value;
            workdir=app.WorkdirectoryEditField.Value;
            solvercall=app.ATPsolverEditField.Value;

            try
                clc;
                if app.BuildcasefilesCheckBox.Value == 1
                    nonechecked=false;
                    if ~isempty(app.UITable.Data)
                        [vars, vartable] = buildCaseData(app);
                        if isempty(app.ProgrammessagesTextArea.Value{1})
                            app.ProgrammessagesTextArea.Value{1} = ['****** Process started.'];
                        else
                            app.ProgrammessagesTextArea.Value{end+1} = ['****** Process started.'];
                        end
                        orig_state = warning;warning('off','all');
                        if isfolder(fullfile(workdir,jobid))
                            rmdir(fullfile(workdir,jobid), 's');
                        end
                        mkdir(fullfile(workdir,jobid));
                        warning(orig_state);

                        if ~isempty(app.INCLUDEINSERTTextArea.Value{1})
                            app.ProgrammessagesTextArea.Value{end+1} = ['Copying linked files...'];
                            [filepath,~,~] = fileparts(app.TemplateATPfileatpEditField.Value);
                            for k=1:size(app.INCLUDEINSERTTextArea.Value,1)
                                fname=app.INCLUDEINSERTTextArea.Value{k};
                                copyfile(fullfile(filepath,fname),fullfile(workdir,jobid));
                                app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Copy: %s - done!',fname)];
                            end
                        end
                        writeATPCaseFiles(app,infile,jobid,vars,vartable);
                    else
                        f = msgbox('No parameters informed. Why not using ATPDraw instead?', 'You lose, fella.','error');
                    end
                end

                if app.RunATPsolverCheckBox.Value == 1
                    nonechecked=false;
                    wdir=fullfile(workdir,jobid);

                    if ~isfile(solvercall)
                        f = msgbox('ATP solver file not found!','You lose, fella.','error');
                        return
                    end

                    dirdata=dir(fullfile(wdir,'*.atp'));
                    if isempty(dirdata)
                        f = msgbox('No ATP files found in the work directory!','You lose, fella.','error');
                        return
                    end
                    if app.CommandlineButton.Value ==1
                        answer = questdlg('Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
                           	'Confirm?', ...
                           	'Yes','No','Yes');
                        if strcmp(answer,'No')
                            return;
                        end

                        app.ProgrammessagesTextArea.Value{end+1} = ['****** Running ATP solver...'];
                        app.ProgrammessagesTextArea.Value{end+1} = ['(use MATLAB Command Window to monitor progress)'];
                        runAllCases(app,wdir,dirdata,solvercall);
                    elseif app.BuildbatfileButton.Value ==1
                        app.ProgrammessagesTextArea.Value{end+1} = ['****** Building .bat file...'];
                        outfile='runAllCases.bat';
                        fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                        out = '@ECHO OFF \n';
                        out = [out sprintf('@cd %s',fullfile(workdir,jobid)) '\n'];
                        for k=1:length(dirdata)
                            fname=dirdata(k).name;
                            [~,fstr,~]=fileparts(fname);
                            cmd=['"' solvercall '" ' '"' fullfile(wdir,fname) '"'];
                            out = [out sprintf('@ECHO ---------- Running case %d, file: %s >> runAllCases.log \n',k,fstr)];
                            out = [out sprintf('@ECHO Started: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                            out = [out cmd '\n'];
                            out = [out sprintf('@ECHO Completed: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                            out = [out sprintf('@if exist MODELS.1 RENAME MODELS.1 %s.1',fstr) '\n'];
                            out = [out sprintf('@if exist MODELS.2 RENAME MODELS.2 %s.2',fstr) '\n'];
                            out = [out sprintf('@RENAME %s %s.done',fname,fstr) '\n'];
                        end
                        out = [out '@if exist *.bin del *.bin' '\n'];
                        out = [out '@if exist *.tmp del *.tmp' '\n'];
                        out = [out '@if exist *.dbg del *.dbg' '\n'];
                        fprintf(fid, out);
                        fclose(fid);

                        outfile='resetRunQueue.bat';
                        fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                        out = '@ECHO OFF \n';
                        out = [out '@rename *.done *.atp' '\n'];
                        out = [out '@del runAllCases.log' '\n'];
                        fprintf(fid, out);
                        fclose(fid);
                    end


                    app.ProgrammessagesTextArea.Value{end+1} = ['All done!'];

                end

                if app.PostprocessfilesCheckBox.Value == 1
                    nonechecked=false;
                end

            catch
                f = msgbox('Error running AutoATPCaseGen!','You lose, fella.','error');
            end
            if nonechecked
                f = msgbox('No task chosen!', 'You lose, fella.','error');
            end
        end

        % Callback function
        function Load2TableButtonPushed(app, event)
            [testfile,msg]=validateFilename(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=app.TemplateATPfileatpEditField.Value;

            [fdata,msg]=loadFromFile(app,fname);

            if isempty(fdata)
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            else
                app.deltaTsEditField.Value=str2double(fdata.dT);
                app.FreqHzEditField.Value=str2double(fdata.freq);
                app.TmaxsEditField.Value=str2double(fdata.Tmax);
                nrows=size(fdata.params,1);
                app.INCLUDEINSERTTextArea.Value=fdata.include;
                app.UITable.Data=[app.UITable.Data;fdata.params];
                %                 app.mainform.Visible = 'off';
                %                 app.mainform.Visible = 'on';
                f = msgbox(sprintf('Found %d valid $PARAMETERS!',nrows), 'Success','help','modal');
                redefRowLabels(app)


            end

        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            newrow={9999,9999,9999,9999,9999,9999,9999,9999,9999,9999};
            app.UITable.Data=[app.UITable.Data;newrow];
            redefRowLabels(app)


        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            delrow=str2double(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if isnan(delrow)
                    app.UITable.Data(end,:)=[];
                else
                    app.UITable.Data(delrow,:)=[];
                end
                redefRowLabels(app)
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete.Value=double(selectedRow);
                end

            end
        end

        % Button pushed function: SaveinputsessionButton
        function SaveinputsessionButtonPushed(app, event)
            saveAllData(app);
        end

        % Button pushed function: LoadinputsessionButton
        function LoadinputsessionButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'atpcasegen_data');
                    writeAllAppValues(app,atpcasegen_data);
                    %                     setColFormats(app);
                catch
                    f = msgbox('Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1,2)==4
                try
                    test=eval(newData);
                catch
                    f = msgbox('Invalid data or MATLAB operator.','You lose, fella.','error');
                    return;
                end
            end
        end

        % Callback function
        function LoadATPButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe';'*.bat'});
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if path ~=0
                app.ATPsolverEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: LoadWorkdirButton
        function LoadWorkdirButtonPushed(app, event)
            path=uigetdir(pwd,'Choose work directory');
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if path ~=0
                app.WorkdirectoryEditField.Value=fullfile(path);
            end
        end

        % Callback function
        function OpendebuggerButtonPushed(app, event)
            x=0;
        end

        % Callback function
        function ClearmessagesButtonPushed(app, event)
            app.ProgrammessagesTextArea.Value='';
        end

        % Button pushed function: RefreshPreview
        function RefreshPreviewButtonPushed(app, event)
            
            try
                plotdata=table2array(app.UITable.Data(:,2:3));
                plotdata=str2double(plotdata);
                scatter(app.UIAxes, plotdata(:,1),plotdata(:,2));
                app.UIAxes.YLim = [0 1.1*max(plotdata(:,2))];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.mainform = uifigure('Visible', 'off');
            app.mainform.Name = 'OHLToolbox v6.1';
            app.mainform.Icon = fullfile(pathToMLAPP, 'gui', 'ohlticon.png');
            app.TabGroup = uitabgroup(app.mainform);
            app.CasedataTab = uitab(app.TabGroup);
            app.CasedataTab.Title = 'Case data';
            app.UIAxes = uiaxes(app.CasedataTab);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'Horizontal [m]')
            ylabel(app.UIAxes, 'Vertical [m]')
            zlabel(app.UIAxes, 'Z')
            app.OHLcrosssectionconductordataPanel = uipanel(app.CasedataTab);
            app.OHLcrosssectionconductordataPanel.Title = 'OHL cross section & conductor data';
            app.UITable = uitable(app.OHLcrosssectionconductordataPanel);
            app.UITable.ColumnName = {'PhaseNum'; 'Horiz'; 'Vert'; 'IntRadius'; 'ExtRadius'; 'CondResist'; 'CondPerm'; 'InsulRadius'; 'InsulPerm'; 'InsulPermit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddrowButton = uibutton(app.OHLcrosssectionconductordataPanel, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '➕ Add row';
            app.DeleterowButton = uibutton(app.OHLcrosssectionconductordataPanel, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = '🗙 Delete row';
            app.ConductordatabaseButton = uibutton(app.OHLcrosssectionconductordataPanel, 'push');
            app.ConductordatabaseButton.Text = '🗃️ Conductor database';
            app.NotesetPhaseNum0toenforceKroneliminationLabel = uilabel(app.OHLcrosssectionconductordataPanel);
            app.NotesetPhaseNum0toenforceKroneliminationLabel.Text = {'Note: set PhaseNum=0 to enforce Kron elimination; '; 'use the same PhaseNum for bundled conductors.'};
            app.CurrentrowLabel = uilabel(app.OHLcrosssectionconductordataPanel);
            app.CurrentrowLabel.Text = 'Current row:';
            app.row2Delete = uieditfield(app.OHLcrosssectionconductordataPanel, 'numeric');
            app.row2Delete.ValueDisplayFormat = '%.0f';
            app.row2Delete.Editable = 'off';
            app.GeneralPanel = uipanel(app.CasedataTab);
            app.GeneralPanel.Title = 'General';
            app.LoadWorkdirButton = uibutton(app.GeneralPanel, 'push');
            app.LoadWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkdirButtonPushed, true);
            app.LoadWorkdirButton.Text = '📂';
            app.WorkdirectoryEditFieldLabel = uilabel(app.GeneralPanel);
            app.WorkdirectoryEditFieldLabel.Text = 'Work directory';
            app.WorkdirectoryEditField = uieditfield(app.GeneralPanel, 'text');
            app.JobIDLabel = uilabel(app.GeneralPanel);
            app.JobIDLabel.Text = 'JobID';
            app.JobIDEditField = uieditfield(app.GeneralPanel, 'text');
            app.ProjectdescriptionLabel = uilabel(app.GeneralPanel);
            app.ProjectdescriptionLabel.Text = 'Project description';
            app.ProjectdescriptionTextArea = uitextarea(app.GeneralPanel);
            app.RefreshPreview = uibutton(app.CasedataTab, 'push');
            app.RefreshPreview.ButtonPushedFcn = createCallbackFcn(app, @RefreshPreviewButtonPushed, true);
            app.RefreshPreview.Text = '↻';
            app.ComputationsettingsTab = uitab(app.TabGroup);
            app.ComputationsettingsTab.Title = 'Computation settings';
            app.FDsoilmodelDropDownLabel = uilabel(app.ComputationsettingsTab);
            app.FDsoilmodelDropDownLabel.Text = 'FD soil model';
            app.FDsoilmodelDropDown = uidropdown(app.ComputationsettingsTab);
            app.FDsoilmodelDropDown.Items = {'Constant', 'Longmire & Smith', 'Portela', 'Alipio & Visacro', 'Datsios & Mikropoulos', 'Scott', 'Messier', 'Visacro & Portela', 'Visacro & Alipio', 'Cigre'};
            app.FDsoilmodelDropDown.Value = 'Constant';
            app.UniformdeepsoillayerPanel = uipanel(app.ComputationsettingsTab);
            app.UniformdeepsoillayerPanel.Title = 'Uniform (deep) soil layer';
            app.GridLayout = uigridlayout(app.UniformdeepsoillayerPanel);
            app.ResistivitymEditFieldLabel = uilabel(app.GridLayout);
            app.ResistivitymEditFieldLabel.Text = 'Resistivity [Ωm]';
            app.ResistivitymEditField = uieditfield(app.GridLayout, 'numeric');
            app.ResistivitymEditField.Value = 100;
            app.PermeabilitypuEditFieldLabel = uilabel(app.GridLayout);
            app.PermeabilitypuEditFieldLabel.Text = 'Permeability [pu]';
            app.PermeabilitypuEditField = uieditfield(app.GridLayout, 'numeric');
            app.PermeabilitypuEditField.Value = 1;
            app.PermittivitypuEditFieldLabel = uilabel(app.GridLayout);
            app.PermittivitypuEditFieldLabel.Text = 'Permittivity [pu]';
            app.PermittivitypuEditField = uieditfield(app.GridLayout, 'numeric');
            app.PermittivitypuEditField.Value = 1;
            app.GroundreturnmodelPanel = uipanel(app.ComputationsettingsTab);
            app.GroundreturnmodelPanel.Title = 'Ground return model';
            app.ImpedanceformulaDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.ImpedanceformulaDropDownLabel.Text = 'Impedance formula';
            app.ImpedanceformulaDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.ImpedanceformulaDropDown.Items = {'Carson', 'Noda', 'Deri', 'Alvarado-Betancourt', 'Sunde', 'Pettersson', 'Semlyen', 'Wise (integral form)', 'TRALIN (requires external file)', 'MoM-SO (requires external file)'};
            app.ImpedanceformulaDropDown.Value = 'Carson';
            app.AdmittanceformulaDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.AdmittanceformulaDropDownLabel.Text = 'Admittance formula';
            app.AdmittanceformulaDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.AdmittanceformulaDropDown.Items = {'Images', 'Pettersson', 'Wise (integral form)', 'TRALIN (requires external file)', 'MoM-SO (requires external file)'};
            app.AdmittanceformulaDropDown.Value = 'Images';
            app.RequestpulparametersplotsCheckBox = uicheckbox(app.GroundreturnmodelPanel);
            app.RequestpulparametersplotsCheckBox.Text = 'Request pul parameters plots';
            app.ModaltransformPanel = uipanel(app.ComputationsettingsTab);
            app.ModaltransformPanel.Title = 'Modal transform';
            app.ModaltransformmatrixDropDownLabel = uilabel(app.ModaltransformPanel);
            app.ModaltransformmatrixDropDownLabel.Text = 'Modal transform matrix';
            app.ModaltransformmatrixDropDown = uidropdown(app.ModaltransformPanel);
            app.ModaltransformmatrixDropDown.Items = {'Wedehpol', 'Fortescue (IN PROGRESS)', 'Clarke (IN PROGRESS)'};
            app.ModaltransformmatrixDropDown.Value = 'Wedehpol';
            app.ModaldecompositionflagDropDownLabel = uilabel(app.ModaltransformPanel);
            app.ModaldecompositionflagDropDownLabel.Text = 'Modal decomposition flag';
            app.ModaldecompositionflagDropDown = uidropdown(app.ModaltransformPanel);
            app.ModaldecompositionflagDropDown.Items = {'QR', 'QR ATP', 'simple_QR', 'NR', 'NR_back', 'SQP', 'SQP_back', 'LM Levenberg-Marquardt', 'LM_back', 'LM_fast', 'LM_alt', 'eigenvalue shuffle QR', 'intercheig QR'};
            app.ModaldecompositionflagDropDown.Value = 'QR';
            app.RequesttransformmatricesplotsCheckBox = uicheckbox(app.ModaltransformPanel);
            app.RequesttransformmatricesplotsCheckBox.Text = 'Request transform matrices plots';
            app.FrequencysweepPanel = uipanel(app.ComputationsettingsTab);
            app.FrequencysweepPanel.Title = 'Frequency sweep';
            app.DecadesSpinnerLabel = uilabel(app.FrequencysweepPanel);
            app.DecadesSpinnerLabel.Text = 'Decades';
            app.DecadesSpinner = uispinner(app.FrequencysweepPanel);
            app.DecadesSpinner.Limits = [1 Inf];
            app.DecadesSpinner.Value = 1;
            app.PointsDecadeSpinnerLabel = uilabel(app.FrequencysweepPanel);
            app.PointsDecadeSpinnerLabel.Text = 'Points/Decade';
            app.PointsDecadeSpinner = uispinner(app.FrequencysweepPanel);
            app.PointsDecadeSpinner.Limits = [1 Inf];
            app.PointsDecadeSpinner.Value = 100;
            app.UselogspacedpointsCheckBox = uicheckbox(app.FrequencysweepPanel);
            app.UselogspacedpointsCheckBox.Text = 'Use logspaced points';
            app.UITable2 = uitable(app.ComputationsettingsTab);
            app.UITable2.ColumnName = {'Resistivity'; 'Permeability'; 'Permittivity'};
            app.UITable2.RowName = {};
            app.UITable2.Enable = 'off';
            app.AddButton = uibutton(app.ComputationsettingsTab, 'push');
            app.AddButton.Enable = 'off';
            app.AddButton.Text = '➕ Add';
            app.DeleteButton = uibutton(app.ComputationsettingsTab, 'push');
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = '🗙 Delete';
            app.row2Delete_2 = uieditfield(app.ComputationsettingsTab, 'numeric');
            app.row2Delete_2.ValueDisplayFormat = '%.0f';
            app.row2Delete_2.Editable = 'off';
            app.row2Delete_2.Enable = 'off';
            app.CurrentrowLabel_2 = uilabel(app.ComputationsettingsTab);
            app.CurrentrowLabel_2.Text = 'Layer:';
            app.LayeredsoilLabel = uilabel(app.ComputationsettingsTab);
            app.LayeredsoilLabel.Text = 'Layered soil:';
            app.PropagationcharacteristicsPanel = uipanel(app.ComputationsettingsTab);
            app.PropagationcharacteristicsPanel.Title = 'Propagation characteristics';
            app.RequestZcplotsCheckBox = uicheckbox(app.PropagationcharacteristicsPanel);
            app.RequestZcplotsCheckBox.Text = 'Request Zc plots';
            app.RequestpropagconstvelplotsCheckBox = uicheckbox(app.PropagationcharacteristicsPanel);
            app.RequestpropagconstvelplotsCheckBox.Text = 'Request propag. const. / vel.  plots';
            app.SavadatatoMATfilesCheckBox = uicheckbox(app.PropagationcharacteristicsPanel);
            app.SavadatatoMATfilesCheckBox.Text = 'Sava data to MAT-files';
            app.MiscPanel = uipanel(app.ComputationsettingsTab);
            app.MiscPanel.Title = 'Misc';
            app.LoadPythonPathButton = uibutton(app.MiscPanel, 'push');
            app.LoadPythonPathButton.Text = '📂';
            app.PythonPathEditFieldLabel = uilabel(app.MiscPanel);
            app.PythonPathEditFieldLabel.Text = {'Python interpreter'; '(required for Bessel '; 'correction)'};
            app.PythonPathEditField = uieditfield(app.MiscPanel, 'text');
            app.EMTPcompliantLineCable_DataMATfileCheckBox = uicheckbox(app.MiscPanel);
            app.EMTPcompliantLineCable_DataMATfileCheckBox.Text = ' EMTP-compliant LineCable_Data  MAT-file';
            app.ATPcompliantPCHJMartimodelCheckBox = uicheckbox(app.MiscPanel);
            app.ATPcompliantPCHJMartimodelCheckBox.Text = 'ATP-compliant PCH JMarti model';
            app.ExportlinemodeltoLabel = uilabel(app.MiscPanel);
            app.ExportlinemodeltoLabel.Text = 'Export line model to:';
            app.LoadExtFileButton = uibutton(app.MiscPanel, 'push');
            app.LoadExtFileButton.Text = '📂';
            app.ExtFileEditFieldLabel = uilabel(app.MiscPanel);
            app.ExtFileEditFieldLabel.Text = {'Impedance/admittance'; 'source file'};
            app.ExtFileEditField = uieditfield(app.MiscPanel, 'text');
            app.OHLTlengthmEditFieldLabel = uilabel(app.ComputationsettingsTab);
            app.OHLTlengthmEditFieldLabel.Text = 'OHLT length [m]';
            app.OHLTlengthmEditField = uieditfield(app.ComputationsettingsTab, 'numeric');
            app.SaveinputsessionButton = uibutton(app.mainform, 'push');
            app.SaveinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinputsessionButtonPushed, true);
            app.SaveinputsessionButton.Text = '🖪 Save input session';
            app.LoadinputsessionButton = uibutton(app.mainform, 'push');
            app.LoadinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadinputsessionButtonPushed, true);
            app.LoadinputsessionButton.Text = '📁 Load input session';
            app.ExitButton = uibutton(app.mainform, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '🚪 Exit';
            app.StartButton = uibutton(app.mainform, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '▶️ Start!';
            % Show the figure after all components are created
            app.mainform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ohlt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainform)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainform)
        end
    end
end